classdef CSNUS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        HomeMenu               matlab.ui.container.Menu
        FileMenu               matlab.ui.container.Menu
        LoadfromFileMenu       matlab.ui.container.Menu
        Panel_2                matlab.ui.container.Panel
        Panel_3                matlab.ui.container.Panel
        SeparatePlotButton     matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        ParameterSettingPanel  matlab.ui.container.Panel
        ContourlevelLabel      matlab.ui.control.Label
        ContourlevelEditField  matlab.ui.control.NumericEditField
        MaxitLabel             matlab.ui.control.Label
        LambdaLabel            matlab.ui.control.Label
        K1SpacedButtonGroup    matlab.ui.container.ButtonGroup
        DisplayLabel           matlab.ui.control.Label
        real                   matlab.ui.control.RadioButton
        abs                    matlab.ui.control.RadioButton
        MaxitEditField         matlab.ui.control.NumericEditField
        LambdaEditField        matlab.ui.control.NumericEditField
        Panel                  matlab.ui.container.Panel
        ReconstructedLabel     matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        lambda = 0.001;
        mask = 0;
        y = 0;
        x = 0;
        xdif = 0;
        objval = 0;
        Callingapp;
        maxit = 200;
        dm = 1;
        cs1 = 0;
        cs2 = 0;
        contourlevel = 40;
        xlname = 0;
        ylname = 0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            global dmvalue;

            app.UIFigure.Name = 'Compressed Sensing NUS MRS Reconstruction';
            app.LambdaEditField.Value = app.lambda;
            app.Callingapp = mainapp;
            app.MaxitEditField.Value = app.maxit;
            app.ContourlevelEditField.Value = app.contourlevel;
            dmvalue = app.K1SpacedButtonGroup.SelectedObject;
            app.SaveButton.Enable = false;
            app.SeparatePlotButton.Enable = false;
        end

        % Menu selected function: LoadfromFileMenu
        function LoadfromFileMenuSelected(app, event)
            [filename, pathname] = uigetfile({'*.mat'});
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return
            else
                data_path = [pathname, filename];
                data_str = load(data_path);
                if ~isequal(data_str, [])
                    app.mask = data_str.GRIN_In.mask;
                    app.y = data_str.GRIN_In.y;
                    app.ylname = 'Chemical Shift (ppm)';
                    try app.cs1 = data_str.GRIN_In.cs1;
                    catch
                       app.cs1 = linspace(-0.5,0.5,size(app.mask,1)) ;  %  revised 20220325
                       app.ylname = '\omega (2\pi)';
                    end
                    app.xlname = 'Chemical Shift (ppm)';
                    try app.cs2 = data_str.GRIN_In.cs2;
                    catch
                       app.cs2 = linspace(-0.5,0.5,size(app.mask,2)) ;  %  revised 20220325
                       app.xlname = '\omega (2\pi)';
                    end
                    if ~isequal(app.mask, 0) && ~isequal(app.y, 0)
                        app.x = 0;
                        app.SaveButton.Enable = false;
                        app.SeparatePlotButton.Enable = false;
%                         app.OKButton.Enable = true;
                        app.StartButton.Enable = true;
                        xpos = get(app.UIAxes, 'xlim');
                        ypos = get(app.UIAxes, 'ylim');
                        text(app.UIAxes,(xpos(1)+xpos(2))/4, (ypos(1) + ypos(2))/2, 'Load data from file successfully!', 'Color', 'red', 'FontSize', 14);
                    end
                end
            end   
        end

        % Callback function
        function LoadfromworkspaceMenuSelected(app, event)
            app.mask = evalin('base', 'mask');
            app.y = evalin('base', 'y');

            if ~isequal(app.mask, 0) && ~isequal(app.y, 0)
                app.x = 0;
                app.SaveButton.Enable = false;
                app.SeparatePlotButton.Enable = false;
%                 app.OKButton.Enable = true;
                app.StartButton.Enable = true;
                errordlg('Load data from workspace successfully!', 'Nice');
            elseif isequal(app.mask, 0)
                errordlg('There is no mask in the workspace!', 'Error');
            elseif isequal(app.y, 0)
                errordlg('There is no y in the workspace!', 'Error');
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.maxit = app.MaxitEditField.Value;
            app.lambda = app.LambdaEditField.Value;
            
            mu=1;
            K = Mask_FFT(app.mask);
            
            GR_InPara.K = K;
            GR_InPara.y = app.y./max(abs(app.y(:)));
            GR_InPara.lambda = app.lambda;
            GR_InPara.A = {1};
            GR_InPara.mu = mu;
            GR_InPara.maxit = app.maxit;
            GR_InPara.RegTerm = {'L1'};
            GR_InPara.Lxp = FSInv(app.mask,mu );
%             [ GR_Out ] = GenReg_ADMM_Sovler6( GR_InPara);
%             app.x = GR_Out.x;
%             app.xdif = GR_Out.xdif;
%             app.objval = GR_Out.objval;
            
            tt = size(app.mask);
            np = size(app.y, 2);
            sz = [tt,np];
            fprintf('This data consist of %d signals in direct dimension \n',np)
            Temp = [];
            for it = 1:np
                NorFac = max(abs(app.y(:,it)));
                GR_InPara.y = app.y(:,it)/NorFac;
                [ GR_Out ] = GRIN_Sovler2( GR_InPara);
                Temp = [Temp ; GR_Out.x*NorFac];
                fprintf('Completing reconstruction  %d out of %d signal(s) \n',it,np)
            end
                app.x = reshape(Temp,sz);
            
            if ~isequal(app.x, 0)
                app.SaveButton.Enable = true;
                app.SeparatePlotButton.Enable = true;
%                 app.OKButton.Enable = false;
                app.StartButton.Enable = false;
                switch app.dm
                    case 0
                        x_disp = real(app.x);
                    case 1
                        x_disp = abs(app.x);
                end
%                 contour(app.UIAxes, app.cs2, app.cs1,x_disp,app.contourlevel);
% %                 title(app.UIAxes, 'Reconstructed');    %  revised 20220325
%                 xlabel(app.UIAxes, app.xlname),ylabel(app.UIAxes, app.ylname);
                if isvector(app.x)
                   plot(app.UIAxes,app.cs1,x_disp);
%                    title('Reconstructed'),
                   xlabel(app.UIAxes,app.xlname),ylabel(app.UIAxes,'normalized (a.u.)')
                    if strcmp(app.xlname,'Chemical Shift (ppm)')
                      set(app.UIAxes,'xdir','reverse');
                    end  
                else
                  dn = length(size(x_disp));
                  if length(size(x_disp))>2
                     x_disp = sum(x_disp,[3:dn]);
                  end    
                  contour(app.UIAxes,app.cs2, app.cs1,x_disp, app.contourlevel);
%                   title('Reconstructed')    %  revised 20220325
                  xlabel(app.UIAxes,app.xlname),ylabel(app.UIAxes,app.ylname)
                  if strcmp(app.xlname,'Chemical Shift (ppm)')
                      set(app.UIAxes,'xdir','reverse');
                  end  
                  if strcmp(app.ylname,'Chemical Shift (ppm)')
                      set(app.UIAxes,'ydir','reverse');
                  end 
                end
                set(app.UIAxes,'linewidth',1,'fontweight','bold')
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~isequal(app.x, 0)
                GRIN_Out = struct; % create the struct variable to be saved
                GRIN_Out.x = app.x;    
                GRIN_Out.mask = app.mask;
                GRIN_Out.datetime =  datetime('now');
                GRIN_Out.application = 'Compressed Sensing NUS MRS Reconstruction';
    
                [filename, pathname] = uiputfile({ '*.mat';'*.*'}, 'load');
                if isequal(filename, 0) || isequal(pathname, 0)
                    errordlg('The file is not exit! Please check again!', 'Error');
                    return;
                else
                    str = [pathname, filename]; 
                    save(str, "GRIN_Out", '-mat');
                end
            else
                errordlg('Please start to solve firstly!', 'Error');
            end
        end

        % Button pushed function: SeparatePlotButton
        function SeparatePlotButtonPushed(app, event)
            if ~isequal(app.x, 0)
                switch app.dm
                    case 0
                        x_disp = real(app.x);
                    case 1
                        x_disp = abs(app.x);
                end
                if isvector(app.x)
                   figure, plot(app.cs1,x_disp),title('Reconstructed'),
                   xlabel(app.xlname),ylabel('normalized (a.u.)')
                    if strcmp(app.xlname,'Chemical Shift (ppm)')
                      set(gca,'xdir','reverse');
                    end  
                else
                  dn = length(size(x_disp));
                  if length(size(x_disp))>2
                     x_disp = sum(x_disp,[3:dn]);
                  end    
                  figure, contour(app.cs2,app.cs1,x_disp,app.contourlevel),title('Reconstructed')    %  revised 20220325
                  xlabel(app.xlname),ylabel(app.ylname)
                  if strcmp(app.xlname,'Chemical Shift (ppm)')
                      set(gca,'xdir','reverse');
                  end  
                  if strcmp(app.ylname,'Chemical Shift (ppm)')
                      set(gca,'ydir','reverse');
                  end 
                end
                set(gca,'linewidth',1,'fontweight','bold')
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Callingapp.CompressedSensingNUSNMRReconstructionButton.Enable = 'on';
%             Home;
            delete(app);
        end

        % Menu selected function: HomeMenu
        function HomeMenuSelected(app, event)
            app.Callingapp.CompressedSensingNUSNMRReconstructionButton.Enable = 'on';
%             Home;
            delete(app);
        end

        % Callback function
        function OKButtonPushed(app, event)
            global dmvalue;

            app.maxit = app.MaxitEditField.Value;
            app.lambda = app.LambdaEditField.Value;
            app.contourlevel = app.ContourlevelEditField.Value;
            dmvalue = app.K1SpacedButtonGroup.SelectedObject;

            switch dmvalue.Text
                case 'abs'
                    app.dm = 1;
                case 'real'
                    app.dm = 0;
            end
            if ~isequal(app.x, 0)
                switch app.dm
                        case 0
                            x_disp = real(app.x);
                        case 1
                            x_disp = abs(app.x);
                end
                contour(app.UIAxes, app.cs2, app.cs1,x_disp,app.contourlevel);
    %                 title(app.UIAxes, 'Reconstructed');    %  revised 20220325
                xlabel(app.UIAxes, app.xlname),ylabel(app.UIAxes, app.ylname);
            end
        end

        % Callback function
        function LoadfrompathMenuSelected(app, event)
            [filename] = uigetdir();
            if isequal(filename, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return
            else
                data_list = dir([filename]);
                data_num = length(data_list);
                if data_num < 1
                    errordlg('The no data in the path! Please check again!', 'Error');
                else
                    for i = 1 : data_num
                        mat_name = data_list(i).name;
                        switch mat_name
                            case 'mask.mat'
                                mask_str = load(fullfile(filename, mat_name));
                                app.mask = mask_str.mask;
                            case 'y.mat'
                                y_str = load(fullfile(filename, mat_name));
                                app.y = y_str.y;
                        end
                    end
                    if ~isequal(app.mask, 0) && ~isequal(app.y, 0)
                        app.x = 0;
                        app.SaveButton.Enable = false;
                        app.SeparatePlotButton.Enable = false;
            %             app.OKButton.Enable = true;
                        app.StartButton.Enable = true;
                        errordlg('Load data from workspace successfully!', 'Nice');
                    elseif isequal(app.mask, 0)
                        errordlg('There is no mask in the workspace!', 'Error');
                    elseif isequal(app.y, 0)
                        errordlg('There is no y in the workspace!', 'Error');
                    end
                end
            end
        end

        % Value changed function: ContourlevelEditField
        function ContourlevelEditFieldValueChanged(app, event)
            app.contourlevel = app.ContourlevelEditField.Value;
            
            if ~isequal(app.x, 0)
                switch app.dm
                        case 0
                            x_disp = real(app.x);
                        case 1
                            x_disp = abs(app.x);
                end
                contour(app.UIAxes, app.cs2, app.cs1,x_disp,app.contourlevel);
    %                 title(app.UIAxes, 'Reconstructed');    %  revised 20220325
                xlabel(app.UIAxes, app.xlname),ylabel(app.UIAxes, app.ylname);
            end
        end

        % Selection changed function: K1SpacedButtonGroup
        function K1SpacedButtonGroupSelectionChanged(app, event)
            global dmvalue;

            dmvalue = app.K1SpacedButtonGroup.SelectedObject;

            switch dmvalue.Text
                case 'abs'
                    app.dm = 1;
                case 'real'
                    app.dm = 0;
            end
            if ~isequal(app.x, 0)
                switch app.dm
                        case 0
                            x_disp = real(app.x);
                        case 1
                            x_disp = abs(app.x);
                end
                contour(app.UIAxes, app.cs2, app.cs1,x_disp,app.contourlevel);
    %                 title(app.UIAxes, 'Reconstructed');    %  revised 20220325
                xlabel(app.UIAxes, app.xlname),ylabel(app.UIAxes, app.ylname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.MenuSelectedFcn = createCallbackFcn(app, @HomeMenuSelected, true);
            app.HomeMenu.Text = 'Home';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadfromFileMenu = uimenu(app.FileMenu);
            app.LoadfromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromFileMenuSelected, true);
            app.LoadfromFileMenu.Text = 'Load from File';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            app.ReconstructedLabel = uilabel(app.Panel);
            app.ReconstructedLabel.Text = 'Reconstructed';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ParameterSettingPanel = uipanel(app.Panel_2);
            app.ParameterSettingPanel.Title = 'Parameter Setting';
            app.LambdaEditField = uieditfield(app.ParameterSettingPanel, 'numeric');
            app.MaxitEditField = uieditfield(app.ParameterSettingPanel, 'numeric');
            app.K1SpacedButtonGroup = uibuttongroup(app.ParameterSettingPanel);
            app.K1SpacedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @K1SpacedButtonGroupSelectionChanged, true);
            app.abs = uiradiobutton(app.K1SpacedButtonGroup);
            app.abs.Text = 'abs';
            app.abs.Value = true;
            app.real = uiradiobutton(app.K1SpacedButtonGroup);
            app.real.Text = 'real';
            app.DisplayLabel = uilabel(app.K1SpacedButtonGroup);
            app.DisplayLabel.Text = 'Display:';
            app.LambdaLabel = uilabel(app.ParameterSettingPanel);
            app.LambdaLabel.Text = 'Lambda:';
            app.MaxitLabel = uilabel(app.ParameterSettingPanel);
            app.MaxitLabel.Text = 'Maxit:';
            app.ContourlevelEditField = uieditfield(app.ParameterSettingPanel, 'numeric');
            app.ContourlevelEditField.ValueChangedFcn = createCallbackFcn(app, @ContourlevelEditFieldValueChanged, true);
            app.ContourlevelLabel = uilabel(app.ParameterSettingPanel);
            app.ContourlevelLabel.Text = 'Contourlevel:';
            app.Panel_3 = uipanel(app.Panel_2);
            app.StartButton = uibutton(app.Panel_3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.Panel_3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SeparatePlotButton = uibutton(app.Panel_3, 'push');
            app.SeparatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeparatePlotButtonPushed, true);
            app.SeparatePlotButton.Text = 'Separate Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSNUS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end