classdef DOSYLRSp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        HomeMenu                matlab.ui.container.Menu
        FileMenu                matlab.ui.container.Menu
        LoadfromBrukerMenu      matlab.ui.container.Menu
        LoadfromVarianMenu      matlab.ui.container.Menu
        LoadfromFileMenu        matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        RawTab                  matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
        ResultTab               matlab.ui.container.Tab
        Panel_3                 matlab.ui.container.Panel
        Panel_2                 matlab.ui.container.Panel
        Panel_4                 matlab.ui.container.Panel
        ThresholdButton         matlab.ui.control.StateButton
        ThresholdEditField      matlab.ui.control.NumericEditField
        DisplayRangeButton      matlab.ui.control.Button
        ParameterSettingButton  matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        SeparatePlotButton      matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        DePhaseButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        NmrData = struct; % Description
        lambdalr = 0.7;
        lambdasp = 33;
        ns = 1;
        ne = 20;
        nn = 191;
        sm = 2;
        CS_Proj = 0;
        DOSY_X = 0;
        ax1 = 0;
        cs = 0;
        cs1 = 0.5;
        cs2 = 5.5;
        DecayRange = 0;
        dc1 = 2;
        dc2 = 13;
        thr = 0.001;
        g = 0;
        BD = 0;
        LD = 0;
        dt = 0;
        b = 0;
        Callingapp;
        TM = 0;
        ypos1 = 0.001;
        contourlevel = 40;
        maxit = 1500;
        DataModel = 1;
        DOSYData = 0;
        start_flag = 0;
        ax2 = 0;
        ax3 = 0;
        xlname = 0;
        load_flag = 0;
    end
    
    methods (Access = public)
        
        function Initiate_NmrData(app)
%             app.NmrData=guidata(hMainFigure);
%             tmp=app.NmrData.version;
%             tmp2=app.NmrData.local;
            clear app.NmrData
            app.NmrData.at=[];
            app.NmrData.arraydim=[];
            app.NmrData.array2nr=[];
            app.NmrData.baselinecorr=[];
            app.NmrData.baselinepoints=[];
            app.NmrData.BasePoints=[];
            app.NmrData.d2=[];
            app.NmrData.dc=0;
            app.NmrData.dcspec=1;
            app.NmrData.decradata=[];
            app.NmrData.DELTA=[];
            app.NmrData.DELTAOriginal=[];
            app.NmrData.DELTAprime=[];
            app.NmrData.delta=[];
            app.NmrData.deltaOriginal=[];
            app.NmrData.disptype=1; %Determines if phase sensitive or absolute value plotting is used
            app.NmrData.DOSYdiffrange=[];
            app.NmrData.dosyconstant=[];
            app.NmrData.dosyconstantOriginal=[];
            app.NmrData.dosydata=[];
            app.NmrData.DOSYopts=[];
            app.NmrData.exclude=[];
            app.NmrData.excludelinepoints=[];
            app.NmrData.ExcludePoints=[];
            app.NmrData.FID=[];
            app.NmrData.FitType=0;
            app.NmrData.filename='';
            app.NmrData.flipnr=[];
            app.NmrData.fn=0;
            app.NmrData.fpmult=0.5;
            app.NmrData.fshift=[];
            app.NmrData.gamma=[];
            app.NmrData.gammaOriginal=[];
            app.NmrData.gcal=[];
            app.NmrData.gcal_orig=[];
            app.NmrData.gf=0;
            app.NmrData.gradnr=[];
            app.NmrData.gw=0;
            app.NmrData.Gzlvl=[];
            app.NmrData.Integral=[];
            app.NmrData.IntPoint=[];
            app.NmrData.IntPointIndex=[];
            app.NmrData.IntPointSort=[];
            app.NmrData.Intscale=1;
            app.NmrData.lb=[];
%             app.NmrData.local=tmp2;
            app.NmrData.lp=[];
            app.NmrData.lpInd=[];
            app.NmrData.locodosydata=[];
            app.NmrData.locodosyopts=[];
            app.NmrData.lrfid=0;
            app.NmrData.lsspec=0;
            app.NmrData.linshift=1;
            app.NmrData.mcrdata=[];
            app.NmrData.MCRopts=[];
            app.NmrData.narray2=[];
            app.NmrData.ncomp=[];
            %app.NmrData.nfid=[];
            app.NmrData.ngrad=[];
            app.NmrData.np=[];
            app.NmrData.nug=[1 0 0 0]; %default as pure exponential
            app.NmrData.nwin=[];
            app.NmrData.order=[];
            app.NmrData.panelpos=[0 0];
            app.NmrData.pfgNmrData=[];
            app.NmrData.pivot=[];
            app.NmrData.pivotxdata=[];
            app.NmrData.pivotydata=[];
            app.NmrData.plottype=1;
            app.NmrData.plotsep=0;
            app.NmrData.probename='undefined';
            app.NmrData.prune=[];
            app.NmrData.pruneArray2=[];
            app.NmrData.RDcentrexdata=[0 0];
            app.NmrData.RDcentreydata=[0 0];
            app.NmrData.RDcentre=0;
            app.NmrData.RDleftxdata=0;
            app.NmrData.RDleftydata=0;
            app.NmrData.RDleft=0;
            app.NmrData.RDrightxdata=[0 0];
            app.NmrData.RDrightydata=[0 0];
            app.NmrData.RDright=0;
            app.NmrData.reference=[];
            app.NmrData.referencexdata=[];
            app.NmrData.referenceydata=[];
            app.NmrData.RRTopts=[];
            %app.NmrData.procpar=[];
            app.NmrData.region=[];
            app.NmrData.rp=[];
            app.NmrData.rpInd=[];
            app.NmrData.scoredata=[];
            app.NmrData.SCOREopts=[];
            app.NmrData.sfrq=[];
            app.NmrData.shiftunits='ppm';
            app.NmrData.sp=[];
            app.NmrData.Specscale=[];
            app.NmrData.SPECTRA=[];
            app.NmrData.sw=[];
            app.NmrData.sw1=[];
            app.NmrData.tau=0;
            app.NmrData.tauOriginal=[];
            app.NmrData.th=0.0;
            app.NmrData.thresxdata=0;
            app.NmrData.thresydata=0;
            app.NmrData.Timescale=[];
            app.NmrData.type='';
%             app.NmrData.version=tmp;
            app.NmrData.xlim=[];
            app.NmrData.xlim_fid=[];
            app.NmrData.ylim=[];
            app.NmrData.ylim_fid=[];
%             app.NmrData.flipnr=str2num(get(hEditFlip,'String'));
            app.NmrData.fn=app.NmrData.np;
%             app.NmrData.gw=str2num(get(hEditGw,'String'));
%             app.NmrData.lb=str2num(get(hEditLb,'String'));
            %AC
            app.NmrData.include=[];
            app.NmrData.includelinepoints=[];
            app.NmrData.IncludePoints=[];
            app.NmrData.startORend=0;
            app.NmrData.winstart=[];
            app.NmrData.winend=[];
            app.NmrData.numcomp=[];
            app.NmrData.concat1dspectra=[];
            app.NmrData.sderrmultiplier=[];
            app.NmrData.LRRegionData=[];
            app.NmrData.issynthetic=0;
            %AC
            %nD specific
            app.NmrData.xlim_1D=[];
            app.NmrData.ylim_1D=[];
            app.NmrData.xlim_2D=[];
            app.NmrData.ylim_2D=[];
                       
%             guidata(hMainFigure,app.NmrData);
        end
        
        function Setup_NmrData(app)
%             app.NmrData=guidata(hMainFigure);
%             set(gcf,'CurrentAxes',hAxes)
            
%             if app.NmrData.issynthetic==1       %AC
%                 slashindex=1;
%             elseif isunix()==1
%                 slashindex=find(app.NmrData.filename=='/'); 
%             else
%                 slashindex=find(app.NmrData.filename=='\');
%             end
            
           % slashindex
%             set(hMainFigure,'Name',...
%                 [app.NmrData.version ' ...' app.NmrData.filename(slashindex(end):end)])
%             set(hScopeGroup,'SelectedObject',hRadioScopeGlobal)
%             set(hEditOrder,'string',2);
%             app.NmrData.order=round(str2double(get(hEditOrder,'string')));
%             delete(app.UIAxes);
            
            app.NmrData.plottype=1;
            app.NmrData.flipnr=1;
            app.NmrData.dc=0;
            app.NmrData.fpmult=0.5;        
            % app.NmrData.nfid=app.NmrData.arraydim;
            app.NmrData.fn=app.NmrData.np;
            app.NmrData.thresydata=0;
            app.NmrData.thresxdata=0;
            app.NmrData.Timescale=linspace...
                (0,app.NmrData.at*(app.NmrData.fn/app.NmrData.np),app.NmrData.fn);
            app.NmrData.exclude=ones(1,app.NmrData.fn);
            app.NmrData.ExcludePoints=[];
            app.NmrData.excludelinepoints=[];
            app.NmrData.IntPoint=[];
            app.NmrData.Integral=ones(1,app.NmrData.fn);
            app.NmrData.region=ones(1,app.NmrData.fn);
            app.NmrData.baselinepoints=[];
            app.NmrData.BasePoints=[];
            % app.NmrData.bpoints=[1 app.NmrData.fn];
            %app.NmrData.baselinecorr=zeros(app.NmrData.fn,app.NmrData.ngrad);
            app.NmrData.baselinecorr=zeros(app.NmrData.fn,app.NmrData.arraydim);
            app.NmrData.xlim_fid=[0 app.NmrData.at];
            app.NmrData.ylim_fid=...
                [min(real(app.NmrData.FID(:,1))) max(real(app.NmrData.FID(:,1)))];
%             set(hEditFn,'string',num2str(app.NmrData.fn));
%             set(hEditNp,'string',num2str(app.NmrData.np));
%             set(hExcludeShow,'Value',0)
            app.NmrData.Specscale=...
                linspace(app.NmrData.sp,(app.NmrData.sw+app.NmrData.sp),app.NmrData.fn);
            app.NmrData.Specscale=...
                linspace(app.NmrData.sp,(app.NmrData.sw+app.NmrData.sp),app.NmrData.fn);
            app.NmrData.pivot=app.NmrData.Specscale(round(app.NmrData.np/2));
%             set(hSliderPh0,'value',app.NmrData.rp);
%             set(hEditPh0,'string',num2str(app.NmrData.rp,4));
%             set(hSliderPh1,'value',app.NmrData.lp);
%             set(hEditPh0,'string',num2str(app.NmrData.lp,4));       
            app.NmrData.SPECTRA=flipud((fftshift(fft(app.NmrData.FID,app.NmrData.fn),1)));
%             guidata(hMainFigure,app.NmrData);        
            app.PhaseSpectrum(app.NmrData.lp,app.NmrData.rp,1);
            %app.NmrData.lpInd=ones(1,app.NmrData.ngrad).*app.NmrData.lp;
            %app.NmrData.rpInd=ones(1,app.NmrData.ngrad).*app.NmrData.rp;
            if isfield(app.NmrData,'lpInd')
                if length(app.NmrData.lpInd)==app.NmrData.arraydim
                    %accept values
                else                
                    app.NmrData.lpInd=ones(1,app.NmrData.arraydim).*app.NmrData.lp;               
                end
            else
                app.NmrData.lpInd=ones(1,app.NmrData.arraydim).*app.NmrData.lp;
                        
            end
            if isfield(app.NmrData,'rpInd')
                if length(app.NmrData.rpInd)==app.NmrData.arraydim
                    %accept values
                else
                     app.NmrData.rpInd=ones(1,app.NmrData.arraydim).*app.NmrData.rp;
                end
            else
                app.NmrData.rpInd=ones(1,app.NmrData.arraydim).*app.NmrData.rp;
            end
            app.NmrData.th=0.0;
%             hPlot=plot(hAxes,app.NmrData.Specscale,...
%                 real(app.NmrData.SPECTRA(:,app.NmrData.flipnr)));
%             set(hPlot,'HitTest','off')
%             xlabel(hAxes,'\bf Chemical shift');
%             set(hAxes,'Xdir','Reverse');
%             axis('tight')
            app.NmrData.xlim_spec=xlim(app.ax1);
            app.NmrData.ylim_spec=ylim(app.ax1);
            app.NmrData.pivotxdata=[app.NmrData.pivot app.NmrData.pivot];
%             app.NmrData.pivotydata=get(hAxes,'ylim');
            app.NmrData.dosyconstantOriginal=app.NmrData.dosyconstant;
            app.NmrData.DELTAOriginal=app.NmrData.DELTA;
            app.NmrData.deltaOriginal=app.NmrData.delta;
            app.NmrData.tauOriginal=app.NmrData.tau;
            app.NmrData.gammaOriginal=app.NmrData.gamma;
            app.NmrData.array2nr=1;
            app.NmrData.narray2=app.NmrData.arraydim/app.NmrData.ngrad;
            app.NmrData.gradnr=1;
            app.NmrData.fshift=zeros(1,app.NmrData.arraydim);
%             set(hEditShift,'string',num2str(0));
%             set(hEditLinear,'string',num2str(0));
            app.NmrData.fshift=zeros(1,app.NmrData.arraydim);
            %AC
            app.NmrData.include=ones(1,app.NmrData.fn);
            app.NmrData.includelinepoints=[];
            app.NmrData.incpoints=[1 app.NmrData.fn];
            app.NmrData.LRRegionData=ones(100,1);
            app.NmrData.SVDcutoff=0.999;
            app.NmrData.locodosyopts=[0 0 0 0 0];
            app.NmrData.pfIterations=2500;
    
            if ~strcmp(app.NmrData.delta,'non existing')
                if (app.NmrData.delta<=0) || (app.NmrData.delta==Inf) || (isnan(app.NmrData.delta))
                    disp('WARNING. Diffusion encoding pulse width is not sensible.')
                    disp('Setting it to a default value of 0.001.')
                    disp('You should probably change it under "Edit -> parameters"')
                    app.NmrData.delta=0.001;
                end
            end
            if ~strcmp(app.NmrData.DELTA,'non existing')
                if length(app.NmrData.DELTA)==1 %If an array is read, it is assumed the values are correct
                    if (app.NmrData.DELTA<=0) || (app.NmrData.DELTA==Inf) || (isnan(app.NmrData.DELTA))
                        disp('WARNING. Diffusion time is not sensible.')
                        disp('Setting it to a default value of 0.1.')
                        disp('You should probably change it under "Edit -> parameters"')
                        app.NmrData.DELTA=0.1;
                    end
                end
            end
            
            app.NmrData.DELTAprime=...
            app.NmrData.dosyconstant./(app.NmrData.gamma.^2.*app.NmrData.delta.^2);
            app.FTCallback();
            app.cs1 = round(min(app.NmrData.Specscale));
            app.cs2 = round(max(app.NmrData.Specscale));
            plot(app.ax1, app.NmrData.Specscale, real(app.NmrData.SPECTRA(:,app.NmrData.flipnr)));
%             title(app.UIAxes,'Spectrum');
            set(app.UIAxes,'Xdir','reverse');
            xlim(app.UIAxes,[app.cs1, app.cs2]);
        end
        
        function PhaseSpectrum(app, lpChange, rpChange, AbsPhase)
            if app.NmrData.plottype==1
                app.NmrData.xlim_spec=xlim(app.ax1);
                app.NmrData.ylim_spec=ylim(app.ax1);
            end
            if isnan(lpChange)
                lpChange=0;
            end
            if isnan(rpChange)
                rpChange=0;
            end
            Rc=1i*(pi/180);

            lpCorr= linspace(0,1,app.NmrData.fn)*lpChange;
            if AbsPhase==1
                %Apply absolute values - no change (after FT or loading in a
                %new experiment)
            elseif AbsPhase==20
                %This means phasing individual spectra from GroupScope_Callback
                %function. Workaround to speed it up (hard to keep
                %elegant code when bolting on)
                PhaseCorrTot=zeros(app.NmrData.fn,app.NmrData.arraydim);
                for k=1:app.NmrData.arraydim
                    lpCorr= linspace(0,1,app.NmrData.fn)*(app.NmrData.lp-app.NmrData.lpInd(k));
                    PhaseCorrTot(:,k)=(exp(Rc*((app.NmrData.rp-app.NmrData.rpInd(k)) + lpCorr)))';
                end
                app.NmrData.SPECTRA=app.NmrData.SPECTRA.*PhaseCorrTot;
            else
                if AbsPhase==2 %autophase or manual edit
                    %nover mind the pivot
                else
                    rpCorrlp=- lpCorr(round(app.NmrData.fn*...
                        (app.NmrData.pivot-app.NmrData.sp)/app.NmrData.sw));
                    rpChange=rpChange+rpCorrlp;
                end
                app.NmrData.lp=app.NmrData.lp+lpChange;
                app.NmrData.rp=app.NmrData.rp+rpChange;
            end
            phaseCorr=...
                (exp(Rc*(rpChange + lpCorr)))';
            %phasing all spectra
            app.NmrData.SPECTRA=app.NmrData.SPECTRA.*repmat...
                (phaseCorr,1,(app.NmrData.arraydim));
        end
        
        function FTCallback(app)
            Timescale=linspace(0,app.NmrData.at*(app.NmrData.fn/app.NmrData.np),app.NmrData.fn);
            Lbfunc=Timescale'*0+1;
            Gwfunc=Timescale'*0+1;
            Winfunc=Gwfunc.*Lbfunc;
            app.NmrData.Specscale=linspace...
                (app.NmrData.sp,...
                (app.NmrData.sw+app.NmrData.sp),...
                app.NmrData.fn);
            app.NmrData.SPECTRA=zeros(app.NmrData.fn,app.NmrData.ngrad);
            %keep the origial FID
            orgfid = app.NmrData.FID;
            
            %leftshift the fid
            if app.NmrData.lrfid>0
                %left shift the fid
                for k=1:app.NmrData.arraydim
                    app.NmrData.FID(:,k)= circshift(app.NmrData.FID(:,k),-app.NmrData.lrfid);
                end
            end
            
            %DC correction
            tmpfid=zeros(app.NmrData.fn,app.NmrData.arraydim);
            dcpoints=round(5*app.NmrData.np/20);
            for k=1:app.NmrData.arraydim
                if app.NmrData.fn>=app.NmrData.np                
                    if app.NmrData.dc
                        tmpfid(1:app.NmrData.np,k)=app.NmrData.FID(1:app.NmrData.np,k) - mean(orgfid(dcpoints:end,k));
                    else
                        tmpfid(1:app.NmrData.np,k)=app.NmrData.FID(1:app.NmrData.np,k);
                    end
                else
                    if app.NmrData.dc
                        tmpfid(1:app.NmrData.fn,k)=app.NmrData.FID(1:app.NmrData.fn,k) - mean(orgfid(dcpoints:end,k));
                    else
                        tmpfid(1:app.NmrData.fn,k)=app.NmrData.FID(1:app.NmrData.fn,k);
                    end
                end
                
            end
            
            %remove shifted points before any zerofilling
            if app.NmrData.fn>=app.NmrData.np
                tmpfid((app.NmrData.np-app.NmrData.lrfid):app.NmrData.np,:)=0;
               % tmpfid((end-app.NmrData.lrfid):end,:)=app.NmrData.FID((end-app.NmrData.lrfid):end,:);
                app.NmrData.FID=zeros(app.NmrData.fn,app.NmrData.arraydim);
                app.NmrData.FID(:,:)=tmpfid.*repmat(Winfunc,1,app.NmrData.arraydim);
            else
                app.NmrData.FID=tmpfid.*repmat(Winfunc,1,app.NmrData.arraydim);
               % app.NmrData.FID((app.NmrData.fn+1):end,:)=[];
            end
            app.NmrData.FID(1,:)=app.NmrData.FID(1,:).*app.NmrData.fpmult;
            app.NmrData.SPECTRA=fft(app.NmrData.FID);
            %app.NmrData.SPECTRA=fft(app.NmrData.FID,app.NmrData.fn);
            app.NmrData.FID=orgfid;
            app.NmrData.SPECTRA=flipud((fftshift(app.NmrData.SPECTRA,1)));
            %         Baseline_function();
            %         IntLine_function();
            app.PhaseSpectrum(app.NmrData.lp,app.NmrData.rp,1);
            
            app.NmrData.plottype=1;
            
            if app.NmrData.dcspec
                dcpoints=round(2*app.NmrData.fn/20);
                for k=1:app.NmrData.arraydim
                    app.NmrData.SPECTRA(:,k)=app.NmrData.SPECTRA(:,k)-mean(app.NmrData.SPECTRA([round(dcpoints/10):dcpoints end-dcpoints:end-round(dcpoints/10)],k));
                end
            end

            for k=1 : app.NmrData.arraydim                  
                    app.NmrData.SPECTRA(:,k)=circshift(app.NmrData.SPECTRA(:,k), app.NmrData.lsspec + app.NmrData.linshift*app.NmrData.lsspec*(k-1));
            end
%             guidata(hMainFigure,app.NmrData);
%             PlotSpectrum();
        end
        
        function AutoPhase(app)
            fac=fminsearch('entropy_fun',[0 0],optimset('TolX',1e-14),app.NmrData.SPECTRA(:,app.NmrData.flipnr));
            lpChange=-fac(2);
            rpChange=-fac(1);
            AbsPhase=2;
            
            app.PhaseSpectrum(lpChange,rpChange,AbsPhase);
        end
        
        function Threshold_function(app)
            %set a threshold
            
            hThresh=findobj(app.ax1,'tag','threshold');
            delete(hThresh);  %(works even if hthresh==[])
            hText = findobj(app.ax1, 'tag', 'text');
            delete(hText);
%             pt=get(app.UIAxes,'currentpoint');
            pt = app.ax1.CurrentPoint;
            ypos=pt(1,2);     

%             switch app.NmrData.shiftunits
%                 case 'ppm'
%                     startunit='ppm';
%                 case 'Hz'
%                     app.NmrData.shiftunits='ppm';
%                     app.NmrData.xlim_spec=app.NmrData.xlim_spec./app.NmrData.sfrq;                
%                     startunit='Hz';
%                 otherwise
%                     error('illegal choice')
%             end
%            
%             speclim=app.NmrData.xlim_spec;
%             speclim=app.NmrData.fn*(speclim-app.NmrData.sp)/app.NmrData.sw;
%             if speclim(1)<1
%                 speclim(1)=1;
%             end
%             if speclim(2)>app.NmrData.fn
%                 speclim(2)=app.NmrData.fn;
%             end
%             speclim=round(speclim); 
%             app.NmrData.th=100*ypos/max(real(app.NmrData.SPECTRA(speclim(1):speclim(2),app.NmrData.flipnr)));
%             
%             %app.NmrData.th=100*ypos/max(real(app.NmrData.SPECTRA(app.NmrData.xlim_spec(1):app.NmrData.xlim_spec(2),app.NmrData.flipnr)));
%             
%             switch startunit
%                 case 'ppm'
%                     %all fine
%                 case 'Hz'
%                     app.NmrData.shiftunits='Hz';
%                     app.NmrData.xlim_spec=app.NmrData.xlim_spec.*app.NmrData.sfrq;
%                     app.NmrData.reference=app.NmrData.reference.*app.NmrData.sfrq;
%                 otherwise
%                     error('illegal choice')
%             end
            app.NmrData.thresydata=[ypos ypos];
%             app.ypos1 = ypos;
            %b=app.NmrData.thresydata
            app.NmrData.thresxdata=get(app.ax1,'xlim');
            line(app.ax1, app.NmrData.thresxdata,app.NmrData.thresydata,...
                'color',[1 0 1],'linewidth', 1.0,...
                'tag','threshold')
            if app.load_flag == 1
                spec1st = abs(app.DOSYData(1, :));
                app.ypos1 = ypos / max(spec1st(:));
                app.ThresholdEditField.Value = roundn(app.ypos1, -4);
%                 ypostext = strcat('threhold:', num2str(app.ypos1));
%                 text(app.ax1, (app.cs2 + app.cs1)/2, ypos+max(spec1st(:))/50, ypostext, 'Color',[1 0 1], 'Tag', 'text');
            else
                spec1st = abs(app.NmrData.SPECTRA(:, 1));
                app.ypos1 = ypos / max(spec1st(:));
                app.ThresholdEditField.Value = roundn(app.ypos1, -4); 
%                 ypostext = strcat('threhold:', num2str(app.ypos1));
%                 text(app.ax1, (app.cs2 + app.cs1)/2, ypos+max(spec1st(:))/50, ypostext, 'Color',[1 0 1], 'Tag', 'text');
            end
            drawnow();  
%             guidata(hMainFigure,app.NmrData);
            %set(hAxes,'ButtonDownFcn',@Axes_ButtonDownFcn)
        end
        
        function updateParameter(app, lambdalr, lambdasp, ns, ne, nn, sm, maxit)
            app.lambdalr = lambdalr;
            app.lambdasp = lambdasp;
            app.ns = ns;
            app.ne = ne;
            app.nn = nn;
            app.sm = sm;
            app.maxit = maxit;
        end
        
        function updataDisaplayRange(app, cs1, cs2, dc1, dc2, contourlevel)
            app.cs1 = cs1;
            app.cs2 = cs2;
            app.dc1 = dc1;
            app.dc2 = dc2;
            app.contourlevel = contourlevel;
            
%             f = figure;
%             f.Position(1) = app.UIFigure.Position(1);
%             f.Position(2) = app.UIFigure.Position(2);
%             ax2 = axes('position',[0.05 0.75 0.9 0.3]);
%             plot(ax2, app.cs, app.CS_Proj);set(gca,'Xdir','reverse');axis off;
%             xlim([app.cs1, app.cs2]);
%             ax3 = axes('position',[0.05 0.23 0.9 0.5]);
%             contour(ax3, app.cs, app.DecayRange, app.DOSY_X,app.contourlevel);xlabel('Chemical Shift/ppm');ylabel('Diffusion Coefficient/10^-^1^0m^2s^-^1');
%             set(ax3,'Ydir','reverse','Xdir','reverse'); 
%     %       set(ax2,'YTick', Comp_pos);
%             xlim([app.cs1, app.cs2]);ylim([app.dc1, app.dc2]);

%           hold(app.Panel_3, 'off')
%           ax3 = uiaxes(app.Panel_3, 'position',[35 280 705 140]);
%           ax2 = uiaxes(app.Panel_3, 'position',[10 0 730 300]);
%           hold(ax3, "off");
%           hold(ax2, 'off');
          plot(app.ax3,app.cs,app.CS_Proj);
          set(app.ax3,'Xdir','reverse');
          xlim(app.ax3, [app.cs1, app.cs2]);
          axis(app.ax3, 'off');
          contour(app.ax2, app.cs, app.DecayRange,app.DOSY_X,app.contourlevel);
          xlabel(app.ax2, app.xlname);ylabel(app.ax2, 'Diffusion Coefficient/10^-^1^0m^2s^-^1');
          set(app.ax2,'Ydir','reverse','Xdir','reverse'); 
          xlim(app.ax2, [app.cs1,app.cs2]);ylim(app.ax2, [app.dc1,app.dc2]);
        end
        
        function updataDisaplayRangeBefore(app, cs1, cs2, DataModue)
            app.cs1 = cs1;
            app.cs2 = cs2;
            app.DataModel = DataModue;
            
            if app.load_flag == 0
                if app.DataModel == 0
                    plot(app.ax1, app.NmrData.Specscale, abs(app.NmrData.SPECTRA(:,app.NmrData.flipnr)));
%                     title(app.UIAxes,'Spectrum');
                    set(app.UIAxes,'Xdir','reverse');
                    xlim(app.UIAxes,[app.cs1, app.cs2]);
                elseif app.DataModel == 1
                    plot(app.ax1, app.NmrData.Specscale, real(app.NmrData.SPECTRA(:,app.NmrData.flipnr)));
%                     title(app.UIAxes,'Spectrum');
                    set(app.UIAxes,'Xdir','reverse');
                    xlim(app.UIAxes,[app.cs1, app.cs2]);
                end
            else
                if app.DataModel == 0
                    cdt = abs(app.DOSYData);
%                     cdt = cdt/max(cdt(:));
                    plot(app.UIAxes, app.cs,cdt(1,:));set(app.UIAxes,'Xdir','reverse');
%                     title(app.UIAxes,'Spectrum');
                    xlim(app.UIAxes,[app.cs1, app.cs2]);
                elseif app.DataModel == 1
                    cdt = real(app.DOSYData);
%                     cdt = cdt/max(cdt(:));
                    plot(app.UIAxes, app.cs,cdt(1,:));set(app.UIAxes,'Xdir','reverse');
%                     title(app.UIAxes,'Spectrum');
                    xlim(app.UIAxes,[app.cs1, app.cs2]);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
                global threshold_value;
                threshold_value = 0;

                app.Callingapp = mainapp;
                app.ax1 = app.UIAxes;
%                 app.ax1 = uiaxes(app.Panel_3);
                app.NmrData.SPECTRA=[];
                app.UIFigure.Name = '2D DOSY Processing';
%                 app.UIFigure.WindowStyle = 'alwaysontop';
                app.SaveButton.Enable = false;
                app.SeparatePlotButton.Enable = false;
                app.ax3 = uiaxes(app.Panel_3, 'position',[35 280 705 140]);
                app.ax2 = uiaxes(app.Panel_3, 'position',[10 0 730 300]);
                app.ax2.NextPlot = 'replaceall';
                app.ax3.NextPlot = 'replaceall';
                app.ThresholdEditField.Value = app.thr;
                app.ThresholdEditField.Editable = 'off';
        end

        % Menu selected function: LoadfromBrukerMenu
        function LoadfromBrukerMenuSelected(app, event)
            app.start_flag = 0;
            app.load_flag = 0;
            app.SaveButton.Enable = false;
            app.DePhaseButton.Enable = true;
            app.ThresholdButton.Enable = true;
            app.StartButton.Enable = true;
            app.ParameterSettingButton.Enable = true;
            app.SeparatePlotButton.Enable = false;
            
            FilePath=uigetdir(pwd,'Choose the directory containing the NMR experiment (ser or fid file)');
            [brukerdata]=brukerimport(0,FilePath);
            if ~isempty(brukerdata)
                app.Initiate_NmrData();
                %app.NmrData=guidata(hMainFigure);
                % setting up the app.NmrData structure - I think it is safest to
                % initialise them all (for the compiled version)
                %I'll keep all points and see if I can sort out the processing
                app.NmrData.type='Bruker';
                app.NmrData.lrfid=brukerdata.digshift;
                app.NmrData.sfrq=brukerdata.sfrq;
                app.NmrData.FID=brukerdata.FID;
                app.NmrData.filename=brukerdata.filename;
                app.NmrData.dosyconstant=brukerdata.dosyconstant;
                app.NmrData.Gzlvl=brukerdata.Gzlvl;
                app.NmrData.ngrad=brukerdata.ngrad;
                app.NmrData.acqus=brukerdata.acqus;
                app.NmrData.acqu2s=brukerdata.acqu2s;
                app.NmrData.np=brukerdata.np;
                app.NmrData.ni=brukerdata.ni;
                app.NmrData.fn=app.NmrData.np;
                app.NmrData.sw=brukerdata.sw;
                app.NmrData.at=brukerdata.at;
                app.NmrData.sp=brukerdata.sp;
                app.NmrData.sp1=brukerdata.sp1;
                app.NmrData.lp=brukerdata.lp;
                app.NmrData.rp=brukerdata.rp;            
                app.NmrData.gamma=brukerdata.gamma;
                app.NmrData.DELTA=brukerdata.DELTA;
                app.NmrData.delta=brukerdata.delta;
                if (isfield(brukerdata,'arraydim'))
                    app.NmrData.arraydim=brukerdata.arraydim;
                else
                    app.NmrData.arraydim=app.NmrData.ngrad;
                end
                if (isfield(brukerdata,'sw1'))
                    app.NmrData.sw1=brukerdata.sw1;
                end
                if (isfield(brukerdata,'tau'))
                    app.NmrData.tau=brukerdata.tau;
                end
                if (isfield(brukerdata,'droppts'))
                    app.NmrData.droppts=brukerdata.droppts;
                end
    
                if isfield(brukerdata,'vdlist')
                    app.NmrData.d2=brukerdata.vdlist';
                    app.NmrData.d2_org=brukerdata.vdlist';
                end
                if isfield(brukerdata,'vclist')
                    app.NmrData.vclist=brukerdata.vclist';
                    app.NmrData.vclist_org=brukerdata.vclist';
                end
                if isfield(brukerdata,'vc_constant')
                    app.NmrData.vc_constant=brukerdata.vc_constant';
                    app.NmrData.vc_constant_org=brukerdata.vc_constant';
                end
                
                clear brukerdata;
%                 guidata(hMainFigure,app.NmrData);
                app.Setup_NmrData();
                
            else
                %do nothing
            end
        end

        % Menu selected function: LoadfromVarianMenu
        function LoadfromVarianMenuSelected(app, event)
            app.start_flag = 0;
            app.load_flag = 0;
            app.SaveButton.Enable = false;
            app.DePhaseButton.Enable = true;
            app.ThresholdButton.Enable = true;
            app.StartButton.Enable = true;
            app.ParameterSettingButton.Enable = true;
            app.SeparatePlotButton.Enable = false;
            
            FilePath=uigetdir(pwd,'Choose the directory containing the NMR experiment (ser or fid file)');
            [vnmrdata]=varianimport(FilePath);
            if ~isempty(vnmrdata)
                app.Initiate_NmrData();
                
                %NmrData=guidata(hMainFigure);
                % setting up the NmrData structure - I think it is safest to
                % initialise them all (for the compiled version)
                app.NmrData.type='Varian';
                app.NmrData.sfrq=vnmrdata.sfrq;
                app.NmrData.FID=vnmrdata.FID;
                app.NmrData.filename=vnmrdata.filename;
                app.NmrData.dosyconstant=vnmrdata.dosyconstant;
                app.NmrData.Gzlvl=vnmrdata.Gzlvl;
                app.NmrData.ngrad=vnmrdata.ngrad;
                %NmrData.procpar=vnmrdata.procpar;
                app.NmrData.np=vnmrdata.np;
                app.NmrData.ni=vnmrdata.ni;
                app.NmrData.sw=vnmrdata.sw;
                app.NmrData.sw1=vnmrdata.sw1;
                app.NmrData.at=vnmrdata.at;
                app.NmrData.sp=vnmrdata.sp;
                app.NmrData.sp1=vnmrdata.sp1;
                app.NmrData.rp=vnmrdata.procpar.rp;
                app.NmrData.rp1=vnmrdata.procpar.rp1;   %AC
                app.NmrData.lp=vnmrdata.procpar.lp;
                app.NmrData.lp1=vnmrdata.procpar.lp1;   %AC
                app.NmrData.gamma=vnmrdata.gamma;
                app.NmrData.DELTA=vnmrdata.DELTA;
                app.NmrData.delta=vnmrdata.delta;
                app.NmrData.gcal_orig=vnmrdata.DAC_to_G;
                if (isfield(vnmrdata,'arraydim'))
                    app.NmrData.arraydim=vnmrdata.arraydim;
                else
                    app.NmrData.arraydim=app.NmrData.ngrad;
                end
                if (isfield(vnmrdata,'sw1'))
                    app.NmrData.sw1=vnmrdata.sw1;
                end
                if (isfield(vnmrdata,'nchunk'))
                    app.NmrData.nchunk=vnmrdata.nchunk;
                end
                if (isfield(vnmrdata,'droppts'))
                    app.NmrData.droppts=vnmrdata.droppts;
                end
                %MN 7Sep12 to accomodate for T1 data
                if (isfield(vnmrdata.procpar,'d2'))
                    app.NmrData.d2=vnmrdata.procpar.d2;
                     app.NmrData.d2_org=vnmrdata.procpar.d2;
                end 
                 %MN 6March14 to accomodate for T2 data
                if (isfield(vnmrdata.procpar,'bigtau'))
                    app.NmrData.bigtau=vnmrdata.procpar.bigtau;
                end  
                %vnmrdata.procpar
                clear vnmrdata;            
%                 guidata(hMainFigure,app.NmrData);
                % NmrData
                app.Setup_NmrData();
%                 plot(app.UIAxes,app.NmrData.Specscale,abs(app.NmrData.SPECTRA(:,app.NmrData.flipnr)));
            else
                %do nothing
            end
        end

        % Button pushed function: DePhaseButton
        function DePhaseButtonPushed(app, event)
            app.AutoPhase();
            plot(app.ax1, app.NmrData.Specscale, real(app.NmrData.SPECTRA(:,app.NmrData.flipnr)));
%             plot(app.ax1, app.NmrData.Specscale, abs(app.NmrData.SPECTRA()));
        end

        % Value changed function: ThresholdButton
        function ThresholdButtonValueChanged(app, event)
            global threshold_value;

            threshold_value = app.ThresholdButton.Value;
            
            if threshold_value == 0
                app.TM = 0;
                hThresh=findobj(app.ax1,'tag','threshold');
                delete(hThresh);
                hText = findobj(app.ax1, 'tag', 'text');
                delete(hText);
                app.ThresholdEditField.Editable = 'off';
            else
                app.ThresholdEditField.Editable = 'on';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
 
            if isequal(app.cs, 0) && isequal(app.g, 0) && isequal(app.LD, 0) && isequal(app.BD, 0)
                app.g=100*app.NmrData.Gzlvl; % gradient values
                app.BD=app.NmrData.DELTAOriginal; % diffusion time
                app.LD=app.NmrData.deltaOriginal; % diffusion encoding time
                app.cs=app.NmrData.Specscale;     % chemical shift
                app.DOSYData = app.NmrData.SPECTRA; % processed experimental data
                if size(app.DOSYData,1)>size(app.DOSYData,2)
                    app.DOSYData = app.DOSYData.';  
                end 
                gamma = 4257.7;
                g2 = (2*pi*gamma*app.g*app.LD).^2*(app.BD-app.LD/3)*1e4;
                app.b = g2;
            end
            
            switch app.DataModel
                    case 0
                        app.dt = abs(app.DOSYData);% processed experimental data
                    case 1
                        app.dt = real(app.DOSYData);
            end
           
            % Kernel Decay Range manual setting
            % from ns to ne Num: nn; Spaced Modle SM : 0 for linear, 1 for logarithm ,otherwise for Auto  
              switch app.sm
                  case 0
                    app.DecayRange=linspace(app.ns,app.ne,app.nn);
                  case 1
                    app.DecayRange=logspace(app.ns,app.ne,app.nn);  
                  case 2
                    app.DecayRange=(1:0.1:20); % defualt
              end       
             
             K = exp(-(app.b).'*app.DecayRange * 1e-10);
             cdt = app.dt;
             Spec1st = cdt(1,:);   % revised 20220508
             Spec1st = Spec1st./max(abs(Spec1st(:)));
             app.dc1 = round(min(app.DecayRange));
             app.dc2 = round(max(app.DecayRange));
%             TM = 1;
            switch app.TM        % TM : 0 for auto,  otherwise manual setting  
                case 0
                   app.thr = 0.001;   % Auto Threshold Value 
                case 1
%                    pt = app.ax1.CurrentPoint;
%                    ypos=pt(1,2); 
                   app.thr = app.ThresholdEditField.Value;
                   
            end
            hf = Spec1st > app.thr;     % revised 20220508
            rdt = cdt(:,hf);
%             app.maxit = 1500; % default   
%             app.contourlevel = 40; % default, 
            
             %% ------------------------------  Reconstruction  --------------------------------
            
            for k = 1:size(K,2)
                norfa(k) = norm(K(:,k),2);
                K(:,k) =  K(:,k)/ norfa(k);
            end    
%             app.lambdalr =0.7;
%             app.lambdasp =33;   
            Y = rdt./max(rdt(:))*2;
            norm_y = sqrt(mean(Y(:).^2));
            Y = Y./norm_y;
            K = K./norm_y;       
            Kinv = (K'*K +  3*eye(size(K,2)))^-1;
            x0 = Kinv*K'*Y;
            
             % ---------------------GRIM-----------------------------   
%             A{1} = 1; A{2} = 1; A{3} = 1;
            GR_InPara.x0 = x0;
            GR_InPara.K = K;
            GR_InPara.y = Y;
            GR_InPara.lambda = [app.lambdalr,app.lambdasp];
            GR_InPara.A = {	1,1,1};
            GR_InPara.mu = 1;
            GR_InPara.maxit = app.maxit;
            GR_InPara.RegTerm = {'NuclearAW','L1','Nonneg'};  
            assignin("base", "GR_InPara111", GR_InPara);
            [ GR_Out ] = GRIN_Sovler2( GR_InPara);
            Conv = GR_Out.xdif;
            X_SPLRA = GR_Out.x;  
                   
            for k = 1:size(K,2)
                X_SPLRA(k,:) =X_SPLRA(k,:)/ norfa(k);
            end 
            app.DOSY_X = zeros(size(K,2),size(cdt,2));
            app.DOSY_X(:,hf) = X_SPLRA;
             
            app.DOSY_X =  app.DOSY_X/max( app.DOSY_X(:));
            app.CS_Proj = sum(app.DOSY_X,1);

            if ~isequal(app.DOSY_X, 0)
              app.start_flag = 1;
              app.SaveButton.Enable = true;
              app.DePhaseButton.Enable = false;
              app.ThresholdButton.Enable = false;
              app.StartButton.Enable = false;
              app.ParameterSettingButton.Enable = false;
              app.SeparatePlotButton.Enable = true;
              app.UIFigure.WindowStyle = 'normal';

%               f = figure;
%               f.Position(1) = app.UIFigure.Position(1);
%               f.Position(2) = app.UIFigure.Position(2);
%               ax2 = axes('position',[0.05 0.75 0.9 0.3]);
%               plot(ax2, app.cs, app.CS_Proj);set(gca,'Xdir','reverse');axis off;
%               xlim([app.cs1, app.cs2]);
%               ax3 = axes('position',[0.05 0.23 0.9 0.5]);
%               contour(ax3, app.cs, app.DecayRange, app.DOSY_X,app.contourlevel);xlabel('Chemical Shift/ppm');ylabel('Diffusion Coefficient/10^-^1^0m^2s^-^1');
%               set(ax3,'Ydir','reverse','Xdir','reverse'); 
%         %       set(ax2,'YTick', Comp_pos);
%               xlim([app.cs1, app.cs2]);ylim([app.dc1, app.dc2]);
%               delete(app.UIAxes);
              
%               hold(ax3, "off");
%               hold(ax2, 'off');
              
              plot(app.ax3,app.cs,app.CS_Proj);
              set(app.ax3,'Xdir','reverse');
              xlim(app.ax3, [app.cs1, app.cs2]);
              axis(app.ax3, 'off');
              contour(app.ax2, app.cs, app.DecayRange,app.DOSY_X,app.contourlevel);
              xlabel(app.ax2, app.xlname);ylabel(app.ax2, 'Diffusion Coefficient (10^{-10}m^2s^{-1})');
              set(app.ax2,'TickLabelInterpreter','latex')
              set(app.ax2,'Ydir','reverse','Xdir','reverse'); 
              xlim(app.ax2, [app.cs1,app.cs2]);ylim(app.ax2, [app.dc1,app.dc2]);
              app.TabGroup.SelectedTab = app.ResultTab;
            end
        end

        % Button pushed function: ParameterSettingButton
        function ParameterSettingButtonPushed(app, event)
            app.ParameterSettingButton.Enable = 'off';
            DosyParameter(app, app.lambdalr, app.lambdasp, app.ns, app.ne, app.nn, app.maxit);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~isequal(app.DOSY_X, 0)
                GRIN_Out = struct; % create the struct variable to be saved
                GRIN_Out.DOSY_X = app.DOSY_X;
                GRIN_Out.CS_Proj = app.CS_Proj;
                GRIN_Out.thr = app.thr;
                GRIN_Out.cs = app.cs;
                GRIN_Out.DecayRange = app.DecayRange;
                GRIN_Out.datetime =  datetime('now');
                GRIN_Out.application = '2D DOSY Processing';

                [filename, pathname] = uiputfile({ '*.mat';'*.*'}, 'load');
                if isequal(filename, 0) || isequal(pathname, 0)
                    errordlg('The file is not exit! Please check again!', 'Error');
                    return;
                else
                    str = [pathname, filename]; 
                    save(str, "GRIN_Out", '-mat');
                end
            else
                errordlg('Please start to solve firstly!', 'Error');
            end
        end

        % Button pushed function: SeparatePlotButton
        function SeparatePlotButtonPushed(app, event)
          f = figure;
          f.Position(1) = app.UIFigure.Position(1);
          f.Position(2) = app.UIFigure.Position(2);
          app.ax2 = axes('position',[0.05 0.75 0.9 0.3]);
          plot(app.ax2, app.cs, app.CS_Proj);set(gca,'Xdir','reverse');axis off;
          xlim([app.cs1, app.cs2]);
          app.ax3 = axes('position',[0.05 0.23 0.9 0.5]);
          contour(app.ax3, app.cs, app.DecayRange, app.DOSY_X, app.contourlevel);xlabel('Chemical Shift/ppm');ylabel('Diffusion Coefficient/10^-^1^0m^2s^-^1');
          set(app.ax3,'Ydir','reverse','Xdir','reverse'); 
    %       set(ax2,'YTick', Comp_pos);
          xlim([app.cs1, app.cs2]);ylim([app.dc1, app.dc2]);
        end

        % Button pushed function: DisplayRangeButton
        function DisplayRangeButtonPushed(app, event)
            app.DisplayRangeButton.Enable = 'off';
            if app.start_flag == 0
                DosyDisplayBefore(app, app.cs1, app.cs2, app.DataModel); 
            else
                DosyDisplay(app, app.cs1, app.cs2, app.dc1, app.dc2, app.contourlevel);
            end
        end

        % Menu selected function: LoadfromFileMenu
        function LoadfromFileMenuSelected(app, event)
            app.start_flag = 0;
            app.load_flag = 1;
            app.SaveButton.Enable = false;
            app.DePhaseButton.Enable = false;
            app.ThresholdButton.Enable = true;
            app.StartButton.Enable = true;
            app.ParameterSettingButton.Enable = true;
            app.SeparatePlotButton.Enable = false;
            
            [filename, pathname] = uigetfile({'*.mat'});
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return
            else
                data_path = [pathname, filename];
                data_str = load(data_path);
                if ~isequal(data_str, [])
%                     app.cs = data_str.GRIN_In.cs;
                    app.b = data_str.GRIN_In.b;
                    app.DOSYData = data_str.GRIN_In.DOSYData;
                    app.xlname = 'Chemical Shift (ppm)';
                    try app.cs = data_str.GRIN_In.cs;
                    catch
                        app.cs = linspace(-0.5,0.5,size(app.DOSYData,2));
                        app.xlname = '\omega (2\pi)';
                    end
                    app.cs1 = round(min(app.cs));
                    app.cs2 = round(max(app.cs));
                    if ~isequal(app.cs, 0) && ~isequal(app.DOSYData, 0) && ~isequal(app.b, 0)
                        if size(app.DOSYData,1) > size(app.DOSYData,2)
                             app.DOSYData = app.DOSYData.';  
                        end
                        cdt = real(app.DOSYData);
%                         cdt = cdt/max(cdt(:));
                        plot(app.UIAxes, app.cs,cdt(1,:));set(app.UIAxes,'Xdir','reverse');
                        xlabel(app.UIAxes,app.xlname)
%                         title(app.UIAxes,'Spectrum');
                        xlim(app.UIAxes,[app.cs1, app.cs2]);
%                         errordlg('Load data from file successfully!', 'Nice');
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Callingapp.DDOSYProcessingButton.Enable = 'on';
%             Home;
            delete(app);
        end

        % Menu selected function: HomeMenu
        function HomeMenuSelected(app, event)
            app.Callingapp.DDOSYProcessingButton.Enable = 'on';
%             Home;
            delete(app);
        end

        % Callback function
        function LoadfromPathMenuSelected(app, event)
            app.start_flag = 0;
            app.SaveButton.Enable = false;
            app.DePhaseButton.Enable = true;
            app.ThresholdButton.Enable = true;
            app.StartButton.Enable = true;
            app.ParameterSettingButton.Enable = true;
            app.SeparatePlotButton.Enable = false;
            
            [filename] = uigetdir();
            if isequal(filename, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return
            else
                data_list = dir([filename]);
                data_num = length(data_list);
                if data_num < 1
                    errordlg('The no data in the path! Please check again!', 'Error');
                else
                    for i = 1 : data_num
                        mat_name = data_list(i).name;
                        switch mat_name
                            case 'cs.mat'
                                cs_str = load(fullfile(filename, mat_name));
                                app.cs = cs_str.cs;
                            case 'g.mat'
                                g_str = load(fullfile(filename, mat_name));
                                app.g = g_str.g;
                            case 'LD.mat'
                                LD_str = load(fullfile(filename, mat_name));
                                app.LD = LD_str.LD;
                            case 'BD.mat'
                                BD_str = load(fullfile(filename, mat_name));
                                app.BD = BD_str.BD;
                            case 'DOSYData.mat'
                                DOSYData_str = load(fullfile(filename, mat_name));
                                app.DOSYData = DOSYData_str.DOSYData;
                        end
                    end
                    if ~isequal(app.cs, 0) && ~isequal(app.g, 0) && ~isequal(app.LD, 0) && ~isequal(app.BD, 0) && ~isequal(app.DOSYData, 0)
                        if size(app.DOSYData,1) > size(app.DOSYData,2)
                             app.DOSYData = app.DOSYData.';  
                        end
                        cdt = real(app.DOSYData);
                        cdt = cdt/max(cdt(:));
                        plot(app.UIAxes, app.cs,cdt(1,:));set(app.UIAxes,'Xdir','reverse');
%                         title(app.UIAxes,'Spectrum');
                        xlim(app.UIAxes,[app.cs1, app.cs2]);
%                         errordlg('Load data from file successfully!', 'Nice');
                    end
                end
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            global threshold_value;
            
            if threshold_value == 1
                app.Threshold_function();
                app.TM = 1;
            elseif threshold_value == 0
                app.TM = 0;
                hThresh=findobj(app.ax1,'tag','threshold');
                delete(hThresh);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.MenuSelectedFcn = createCallbackFcn(app, @HomeMenuSelected, true);
            app.HomeMenu.Text = 'Home';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadfromBrukerMenu = uimenu(app.FileMenu);
            app.LoadfromBrukerMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromBrukerMenuSelected, true);
            app.LoadfromBrukerMenu.Text = 'Load from Bruker';
            app.LoadfromVarianMenu = uimenu(app.FileMenu);
            app.LoadfromVarianMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromVarianMenuSelected, true);
            app.LoadfromVarianMenu.Text = 'Load from Varian';
            app.LoadfromFileMenu = uimenu(app.FileMenu);
            app.LoadfromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromFileMenuSelected, true);
            app.LoadfromFileMenu.Text = 'Load from File';
            app.Panel_2 = uipanel(app.UIFigure);
            app.DePhaseButton = uibutton(app.Panel_2, 'push');
            app.DePhaseButton.ButtonPushedFcn = createCallbackFcn(app, @DePhaseButtonPushed, true);
            app.DePhaseButton.Text = 'DePhase';
            app.StartButton = uibutton(app.Panel_2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SeparatePlotButton = uibutton(app.Panel_2, 'push');
            app.SeparatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeparatePlotButtonPushed, true);
            app.SeparatePlotButton.Text = 'Separate Plot';
            app.SaveButton = uibutton(app.Panel_2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ParameterSettingButton = uibutton(app.Panel_2, 'push');
            app.ParameterSettingButton.ButtonPushedFcn = createCallbackFcn(app, @ParameterSettingButtonPushed, true);
            app.ParameterSettingButton.Text = 'Parameter Setting';
            app.DisplayRangeButton = uibutton(app.Panel_2, 'push');
            app.DisplayRangeButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayRangeButtonPushed, true);
            app.DisplayRangeButton.Text = 'Display Range';
            app.Panel_4 = uipanel(app.Panel_2);
            app.ThresholdEditField = uieditfield(app.Panel_4, 'numeric');
            app.ThresholdButton = uibutton(app.Panel_4, 'state');
            app.ThresholdButton.ValueChangedFcn = createCallbackFcn(app, @ThresholdButtonValueChanged, true);
            app.ThresholdButton.Text = 'Threshold';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RawTab = uitab(app.TabGroup);
            app.RawTab.Title = 'Raw';
            app.UIAxes = uiaxes(app.RawTab);
            title(app.UIAxes, 'Spectrum')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ResultTab = uitab(app.TabGroup);
            app.ResultTab.Title = 'Result';
            app.Panel_3 = uipanel(app.ResultTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOSYLRSp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end