classdef DosyDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OKButton                    matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        ContourLevelEditField       matlab.ui.control.NumericEditField
        ContourLevelEditFieldLabel  matlab.ui.control.Label
        ppmLabel                    matlab.ui.control.Label
        m2s1Label                   matlab.ui.control.Label
        fromEditField_2             matlab.ui.control.NumericEditField
        fromEditField_2Label        matlab.ui.control.Label
        toEditField_2               matlab.ui.control.NumericEditField
        toEditField_2Label          matlab.ui.control.Label
        toEditField                 matlab.ui.control.NumericEditField
        toEditFieldLabel            matlab.ui.control.Label
        fromEditField               matlab.ui.control.NumericEditField
        fromEditFieldLabel          matlab.ui.control.Label
        ChemShiftLabel              matlab.ui.control.Label
        DiffCoefLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        Callingapp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, cs1, cs2, dc1, dc2, contourlevel)
            app.UIFigure.Name = 'DOSYLRSp Display Range Setting';
            app.UIFigure.WindowStyle = 'alwaysontop';
            app.Callingapp = mainapp;
            app.UIFigure.Position(1) = mainapp.UIFigure.Position(1);
            app.UIFigure.Position(2) = mainapp.UIFigure.Position(2);
            app.fromEditField.Value = dc1;
            app.toEditField.Value = dc2;
            app.fromEditField_2.Value = cs1;
            app.toEditField_2.Value = cs2;
            app.ContourLevelEditField.Value = contourlevel;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            cs1 = app.fromEditField_2.Value;
            cs2 = app.toEditField_2.Value;
            dc1 = app.fromEditField.Value;
            dc2 = app.toEditField.Value;
            contourlevel = app.ContourLevelEditField.Value;

            updataDisaplayRange(app.Callingapp, cs1, cs2, dc1, dc2, contourlevel);
            app.Callingapp.DisplayRangeButton.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Callingapp.DisplayRangeButton.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.DiffCoefLabel = uilabel(app.Panel);
            app.DiffCoefLabel.Text = 'Diff Coef :';
            app.ChemShiftLabel = uilabel(app.Panel);
            app.ChemShiftLabel.Text = 'Chem Shift :';
            app.fromEditFieldLabel = uilabel(app.Panel);
            app.fromEditFieldLabel.Text = 'from';
            app.fromEditField = uieditfield(app.Panel, 'numeric');
            app.toEditFieldLabel = uilabel(app.Panel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.Panel, 'numeric');
            app.toEditField_2Label = uilabel(app.Panel);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.Panel, 'numeric');
            app.fromEditField_2Label = uilabel(app.Panel);
            app.fromEditField_2Label.Text = 'from';
            app.fromEditField_2 = uieditfield(app.Panel, 'numeric');
            app.m2s1Label = uilabel(app.Panel);
            app.m2s1Label.Text = '10^(-10)m^2s^(-1)';
            app.ppmLabel = uilabel(app.Panel);
            app.ppmLabel.Text = 'ppm';
            app.ContourLevelEditFieldLabel = uilabel(app.Panel);
            app.ContourLevelEditFieldLabel.Text = 'ContourLevel :';
            app.ContourLevelEditField = uieditfield(app.Panel, 'numeric');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DosyDisplay(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end