classdef DosyDisplayBefore < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        OKButton              matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        K1SpacedButtonGroup   matlab.ui.container.ButtonGroup
        DataModelLabel        matlab.ui.control.Label
        real                  matlab.ui.control.RadioButton
        abs                   matlab.ui.control.RadioButton
        ppmLabel              matlab.ui.control.Label
        fromEditField_2       matlab.ui.control.NumericEditField
        fromEditField_2Label  matlab.ui.control.Label
        toEditField_2         matlab.ui.control.NumericEditField
        toEditField_2Label    matlab.ui.control.Label
        ChemShiftLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        Callingapp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, cs1, cs2, dm)
            global DataModue;

            app.UIFigure.Name = 'DOSYLRSp Display Range Setting';
            app.UIFigure.WindowStyle = 'alwaysontop';
            app.Callingapp = mainapp;
%             position = mainapp.UIFigure.Position;
%             [left2 bottom2 width2 height2] = app.UIFigure.Position;
            app.UIFigure.Position(1) = mainapp.UIFigure.Position(1);
            app.UIFigure.Position(2) = mainapp.UIFigure.Position(2);
            app.fromEditField_2.Value = cs1;
            app.toEditField_2.Value = cs2;
            if dm == 1
                app.real.Value = true;
            elseif dm == 0
                app.abs.Value = true;
            end
            DataModue = app.K1SpacedButtonGroup.SelectedObject;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            global DataModue;

            switch DataModue.Text
                case 'abs'
                    dm = 0;
                case 'real'
                    dm = 1;
            end

            cs1 = app.fromEditField_2.Value;
            cs2 = app.toEditField_2.Value;

            updataDisaplayRangeBefore(app.Callingapp, cs1, cs2, dm);
            app.Callingapp.DisplayRangeButton.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Callingapp.DisplayRangeButton.Enable = 'on';
            delete(app);
        end

        % Selection changed function: K1SpacedButtonGroup
        function K1SpacedButtonGroupSelectionChanged(app, event)
            global DataModue;

            DataModue = app.K1SpacedButtonGroup.SelectedObject;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.ChemShiftLabel = uilabel(app.Panel);
            app.ChemShiftLabel.Text = 'Chem Shift :';
            app.toEditField_2Label = uilabel(app.Panel);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.Panel, 'numeric');
            app.fromEditField_2Label = uilabel(app.Panel);
            app.fromEditField_2Label.Text = 'from';
            app.fromEditField_2 = uieditfield(app.Panel, 'numeric');
            app.ppmLabel = uilabel(app.Panel);
            app.ppmLabel.Text = 'ppm';
            app.K1SpacedButtonGroup = uibuttongroup(app.Panel);
            app.K1SpacedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @K1SpacedButtonGroupSelectionChanged, true);
            app.abs = uiradiobutton(app.K1SpacedButtonGroup);
            app.abs.Text = 'abs';
            app.abs.Value = true;
            app.real = uiradiobutton(app.K1SpacedButtonGroup);
            app.real.Text = 'real';
            app.DataModelLabel = uilabel(app.K1SpacedButtonGroup);
            app.DataModelLabel.Text = 'DataModel:';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DosyDisplayBefore(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end