classdef DosyParameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OKButton               matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        MaxitEditField         matlab.ui.control.NumericEditField
        MaxitLabel             matlab.ui.control.Label
        K1SpacedButtonGroup    matlab.ui.container.ButtonGroup
        SpacedLabel            matlab.ui.control.Label
        K1Logarithm            matlab.ui.control.RadioButton
        K1Liner                matlab.ui.control.RadioButton
        NumEditField           matlab.ui.control.NumericEditField
        NumEditFieldLabel      matlab.ui.control.Label
        toEditField            matlab.ui.control.NumericEditField
        toEditFieldLabel       matlab.ui.control.Label
        fromEditField          matlab.ui.control.NumericEditField
        fromEditFieldLabel     matlab.ui.control.Label
        KerneldecayrangeLabel  matlab.ui.control.Label
        LambdaSettingPanel     matlab.ui.container.Panel
        SparseEditField        matlab.ui.control.NumericEditField
        SparseEditFieldLabel   matlab.ui.control.Label
        LowrankEditField       matlab.ui.control.NumericEditField
        LowrankLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        Callingapp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, lambdalr, lambdasp, ns, ne, nn, maxit)
            global spacedButton;
            
            app.UIFigure.Name = 'DOSYLRSp Parameter Setting';
            app.UIFigure.WindowStyle = 'alwaysontop';
            app.Callingapp = mainapp;
            app.UIFigure.Position(1) = mainapp.UIFigure.Position(1);
            app.UIFigure.Position(2) = mainapp.UIFigure.Position(2);
            app.LowrankEditField.Value = lambdalr;
            app.SparseEditField.Value = lambdasp;
            app.fromEditField.Value = ns;
            app.toEditField.Value = ne;
            app.NumEditField.Value = nn;
            app.MaxitEditField.Value = maxit;
            spacedButton = app.K1SpacedButtonGroup.SelectedObject;
        end

        % Selection changed function: K1SpacedButtonGroup
        function K1SpacedButtonGroupSelectionChanged(app, event)
            global spacedButton;
            
            spacedButton = app.K1SpacedButtonGroup.SelectedObject;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            global spacedButton;

            switch spacedButton.Text
                case 'Linear'
                    sm = 0;
                case 'Logarithm'
                    sm = 1;
            end

            lambdalr = app.LowrankEditField.Value;
            lambdasp = app.SparseEditField.Value;
            ns = app.fromEditField.Value;
            ne = app.toEditField.Value;
            nn = app.NumEditField.Value;
            maxit = app.MaxitEditField.Value;

            updateParameter(app.Callingapp, lambdalr, lambdasp, ns, ne, nn, sm, maxit);
            app.Callingapp.ParameterSettingButton.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Callingapp.ParameterSettingButton.Enable = 'on';
            delete(app);
        end

        % Callback function
        function K1SpacedButtonGroup_2SelectionChanged(app, event)
            global DataModelButton;

            DataModelButton = app.K1SpacedButtonGroup_2.SelectedObject;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LambdaSettingPanel = uipanel(app.UIFigure);
            app.LambdaSettingPanel.Title = 'Lambda Setting';
            app.LowrankLabel = uilabel(app.LambdaSettingPanel);
            app.LowrankLabel.Text = 'Low-rank:';
            app.LowrankEditField = uieditfield(app.LambdaSettingPanel, 'numeric');
            app.SparseEditFieldLabel = uilabel(app.LambdaSettingPanel);
            app.SparseEditFieldLabel.Text = 'Sparse:';
            app.SparseEditField = uieditfield(app.LambdaSettingPanel, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.KerneldecayrangeLabel = uilabel(app.Panel);
            app.KerneldecayrangeLabel.Text = 'Kernel decay range:';
            app.fromEditFieldLabel = uilabel(app.Panel);
            app.fromEditFieldLabel.Text = 'from';
            app.fromEditField = uieditfield(app.Panel, 'numeric');
            app.toEditFieldLabel = uilabel(app.Panel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.Panel, 'numeric');
            app.NumEditFieldLabel = uilabel(app.Panel);
            app.NumEditFieldLabel.Text = 'Num:';
            app.NumEditField = uieditfield(app.Panel, 'numeric');
            app.K1SpacedButtonGroup = uibuttongroup(app.Panel);
            app.K1SpacedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @K1SpacedButtonGroupSelectionChanged, true);
            app.K1Liner = uiradiobutton(app.K1SpacedButtonGroup);
            app.K1Liner.Text = 'Linear';
            app.K1Liner.Value = true;
            app.K1Logarithm = uiradiobutton(app.K1SpacedButtonGroup);
            app.K1Logarithm.Text = 'Logarithm';
            app.SpacedLabel = uilabel(app.K1SpacedButtonGroup);
            app.SpacedLabel.Text = 'Spaced:';
            app.MaxitLabel = uilabel(app.Panel);
            app.MaxitLabel.Text = 'Maxit :';
            app.MaxitEditField = uieditfield(app.Panel, 'numeric');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DosyParameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end