classdef HLRDenoise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HomeMenu                    matlab.ui.container.Menu
        FileMenu                    matlab.ui.container.Menu
        LoadfromFileMenu            matlab.ui.container.Menu
        Panel_3                     matlab.ui.container.Panel
        SeparatePlotButton          matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        Panel_4                     matlab.ui.container.Panel
        MaxitLabel                  matlab.ui.control.Label
        LambdaLabel                 matlab.ui.control.Label
        MaxitEditField              matlab.ui.control.NumericEditField
        LambdaEditField             matlab.ui.control.NumericEditField
        Panel_2                     matlab.ui.container.Panel
        ReconstructedSpectrumLabel  matlab.ui.control.Label
        UIAxes_4                    matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        NoisySpectrumLabel          matlab.ui.control.Label
        UIAxes_3                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        lambda = 0.01;
%         mask = 0;
        y = 0;
        x = 0;
        xdif = 0;
        objval = 0;
        cs = 0;
        Callingapp;
        maxit = 200;
        xlname = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Name = 'Hankel Low-Rank NMR Spectra Denoise';
            app.LambdaEditField.Value = app.lambda;
            app.Callingapp = mainapp;
            app.MaxitEditField.Value = app.maxit;
            app.SaveButton.Enable = false;
            app.SeparatePlotButton.Enable = false;
        end

        % Menu selected function: LoadfromFileMenu
        function LoadfromFileMenuSelected(app, event)
            
            [filename, pathname] = uigetfile({'*.mat'});
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return
            else
                data_path = [pathname, filename];
                data_str = load(data_path);
                if ~isequal(data_str, [])
                    app.y = data_str.GRIN_In.y;
                    app.xlname = 'Chemical Shift (ppm)';
                    try app.cs = data_str.GRIN_In.cs;
                    catch
                        app.cs = linspace(0, 1, length(app.y));
                        app.xlname = '\omega (2\pi)';
                    end
                    if  ~isequal(app.y, 0)
                        app.SaveButton.Enable = false;
                        app.SeparatePlotButton.Enable = false;
                        app.StartButton.Enable = true;
                        SpecNoisy = abs(fftshift(fft(app.y)));
                        plot(app.UIAxes_3,app.cs,SpecNoisy /max(SpecNoisy ),'linewidth',1);
%                         title(app.UIAxes_3,'Noisy Spectrum');
                        xlabel(app.UIAxes_3,app.xlname), ylabel(app.UIAxes_3,'normalized (a.u.)');
                    end
                end
            end
        end

        % Callback function
        function LoadfromworkspaceMenuSelected(app, event)
%             app.mask = evalin('base', 'mask');
            app.SaveButton.Enable = false;
            app.SeparatePlotButton.Enable = false;
            app.LambdaButton.Enable = true;
            app.MaxitButton.Enable = true;
            app.StartButton.Enable = true;
            app.y = evalin('base', 'y');
            app.xlname = 'Chemical Shift (ppm)';
            try app.cs = evalin('base', 'cs');
            catch
                app.cs = linspace(0, 1, length(app.y));
                app.xlname = '\omega (2\pi)';
            end

            if ~isequal(app.y, 0)
                SpecNoisy = abs(fftshift(fft(app.y)));
                plot(app.UIAxes_3,app.cs,SpecNoisy /max(SpecNoisy ),'linewidth',1);
%                 title(app.UIAxes_3,'Noisy Spectrum');
                xlabel(app.UIAxes_3,app.xlname), ylabel(app.UIAxes_3,'normalized (a.u.)');
            elseif isequal(app.y, 0)
                errordlg('There is no y in the workspace!', 'Error');
            end
        end

        % Callback function
        function LambdaButtonPushed(app, event)
            app.lambda = app.LambdaEditField.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.lambda = app.LambdaEditField.Value;
            app.maxit = app.MaxitEditField.Value;

            mask = ones(size(app.y));% full sampling

            K = eye(length(app.y)); % identity matrix
            N = length(app.y);
            hr = round(N/2); hc = N+1-hr;
%             if exist("cs") ~=1
%                cs = linspace(0,1,N)  
%             end
            NorFac = max(abs(app.y(:)));
            GR_InPara.K = K;
            GR_InPara.y = app.y/NorFac;
            GR_InPara.lambda = [app.lambda];
            GR_InPara.A = {HMOperator(hr,hc )};
            GR_InPara.mu = 1;
            GR_InPara.maxit =app.maxit;
            GR_InPara.RegTerm = {'Nuclear'};
            GR_InPara.Lxp = HMInv(mask,GR_InPara.mu,hr,hc);
            
            [ GR_Out ] = GRIN_Sovler2( GR_InPara);
            app.x = GR_Out.x*NorFac;
            app.xdif = GR_Out.xdif;
            app.objval = GR_Out.objval;
            
            if ~isequal(app.x, 0)
                app.StartButton.Enable = false;
                app.SaveButton.Enable = true;
                app.SeparatePlotButton.Enable = true;
                SpecRec = abs(fftshift(fft(app.x)));
                plot(app.UIAxes_4,app.cs,SpecRec/max(SpecRec),'linewidth',1);
%                 title(app.UIAxes_4,'Reconstructed Spectrum')
                xlabel(app.UIAxes_4,app.xlname), ylabel(app.UIAxes_4,'normalized (a.u.)')
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
%             global SpecRec;
%             global cs;

            if ~isequal(app.x, 0)
                SpecRec = abs(fftshift(fft(app.x)));
                GRIN_Out = struct; % create the struct variable to be saved
                GRIN_Out.fid_denoise = app.x;   %  
                GRIN_Out.spec_denoise = SpecRec;
                GRIN_Out.cs = app.cs;% chemical shift
                GRIN_Out.datetime =  datetime('now');
                GRIN_Out.application = 'Hankel Low-Rank NMR Spectra Denoise';
            [filename, pathname] = uiputfile({ '*.mat';'*.*'}, 'load');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return;
            else
                str = [pathname, filename]; 
                save(str, "GRIN_Out", '-mat');
            end
            else
                errordlg('Please start to solve firstly!', 'Error');
            end
        end

        % Button pushed function: SeparatePlotButton
        function SeparatePlotButtonPushed(app, event)
%             global SpecRec;
            figure;
            SpecRec = abs(fftshift(fft(app.x)));
            plot(app.cs,SpecRec/max(SpecRec),'linewidth',1);title('Reconstructed Spectrum')
            xlabel(app.xlname), ylabel('normalized (a.u.)');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Callingapp.HankelLowRankNMRSpectraDenoiseButton.Enable = 'on';
%             Home;
            delete(app);
        end

        % Menu selected function: HomeMenu
        function HomeMenuSelected(app, event)
            app.Callingapp.HankelLowRankNMRSpectraDenoiseButton.Enable = 'on';
%             Home;
            delete(app);
        end

        % Callback function
        function MaxitButtonPushed(app, event)
            app.maxit = app.MaxitEditField.Value;
        end

        % Callback function
        function LoadfrompathMenuSelected(app, event)
            app.SaveButton.Enable = false;
            app.SeparatePlotButton.Enable = false;
            app.LambdaButton.Enable = true;
            app.MaxitButton.Enable = true;
            app.StartButton.Enable = true;
            [filename] = uigetdir();
            if isequal(filename, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return
            else
                data_list = dir([filename]);
                data_num = length(data_list);
                if data_num < 1
                    errordlg('The no data in the path! Please check again!', 'Error');
                else
                    for i = 1 : data_num
                        mat_name = data_list(i).name;
                        switch mat_name
                            case 'y.mat'
                                y_str = load(fullfile(filename, mat_name));
                                app.y = y_str.y;
                        end
                    end
                    if ~isequal(app.y, 0)
                        app.cs = linspace(0, 1, length(app.y));
                        SpecNoisy = abs(fftshift(fft(app.y)));
                        plot(app.UIAxes_3,app.cs,SpecNoisy /max(SpecNoisy ),'linewidth',1);
%                         title(app.UIAxes_3,'Noisy Spectrum');
                        xlabel(app.UIAxes_3,'chemical shift (ppm)'), ylabel(app.UIAxes_3,'normalized (a.u.)');
                    elseif isequal(app.y, 0)
                        errordlg('There is no y in the workspace!', 'Error');
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.MenuSelectedFcn = createCallbackFcn(app, @HomeMenuSelected, true);
            app.HomeMenu.Text = 'Home';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadfromFileMenu = uimenu(app.FileMenu);
            app.LoadfromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromFileMenuSelected, true);
            app.LoadfromFileMenu.Text = 'Load from File';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.Panel);
            app.NoisySpectrumLabel = uilabel(app.Panel);
            app.NoisySpectrumLabel.Text = 'Noisy Spectrum';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes_4 = uiaxes(app.Panel_2);
            app.ReconstructedSpectrumLabel = uilabel(app.Panel_2);
            app.ReconstructedSpectrumLabel.Text = 'Reconstructed Spectrum';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_4 = uipanel(app.Panel_3);
            app.LambdaEditField = uieditfield(app.Panel_4, 'numeric');
            app.MaxitEditField = uieditfield(app.Panel_4, 'numeric');
            app.LambdaLabel = uilabel(app.Panel_4);
            app.LambdaLabel.Text = 'Lambda:';
            app.MaxitLabel = uilabel(app.Panel_4);
            app.MaxitLabel.Text = 'Maxit:';
            app.StartButton = uibutton(app.Panel_3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.Panel_3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SeparatePlotButton = uibutton(app.Panel_3, 'push');
            app.SeparatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeparatePlotButtonPushed, true);
            app.SeparatePlotButton.Text = 'Separate Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HLRDenoise(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end