classdef HLRNUSRec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HomeMenu                    matlab.ui.container.Menu
        FileMenu                    matlab.ui.container.Menu
        LoadfromFileMenu            matlab.ui.container.Menu
        Panel_3                     matlab.ui.container.Panel
        SeparatePlotButton          matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        Panel_4                     matlab.ui.container.Panel
        MaxitLabel                  matlab.ui.control.Label
        LambdaLabel                 matlab.ui.control.Label
        MaxitEditField              matlab.ui.control.NumericEditField
        LambdaEditField             matlab.ui.control.NumericEditField
        Panel_2                     matlab.ui.container.Panel
        ReconstructedSpectrumLabel  matlab.ui.control.Label
        UIAxes_4                    matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        NUSZeroFillSpectrumLabel    matlab.ui.control.Label
        UIAxes_3                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        lambda = 0.001;
        mask = 0;
        y = 0;
        x = 0;
        xdif = 0;
        objval = 0;
        cs = 0;
        maxit = 1000;
        Callingapp;
        xlname = 0;
        np = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Name = 'Hankel Low-Rank NMR NUS Reconstruction';
            app.LambdaEditField.Value = app.lambda;
            app.Callingapp = mainapp;
            app.MaxitEditField.Value = app.maxit;
            app.SaveButton.Enable = false;
            app.SeparatePlotButton.Enable = false;
        end

        % Menu selected function: LoadfromFileMenu
        function LoadfromFileMenuSelected(app, event)
            [filename, pathname] = uigetfile({'*.mat'});
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return
            else
                data_path = [pathname, filename];
                data_str = load(data_path);
                if ~isequal(data_str, [])
                    app.mask = data_str.GRIN_In.mask;
                    app.y = data_str.GRIN_In.y;
                    app.xlname = 'Chemical Shift (ppm)';
                    try app.cs = data_str.GRIN_In.cs;
                    catch
                        app.cs = linspace(-0.5,0.5,length(app.mask));
                        app.xlname = '\omega (2\pi)';
                    end
                    if ~isequal(app.mask, 0) && ~isequal(app.y, 0)
                        
                        app.SaveButton.Enable = false;
                        app.SeparatePlotButton.Enable = false;
                        app.StartButton.Enable = true;
                        app.np = size(app.y, 2);
                        K = Mask(app.mask);
                        if app.np == 1
                            SpecZeroFill = abs(fftshift(fft(K'*app.y)));
                            plot(app.UIAxes_3,app.cs,SpecZeroFill /max(SpecZeroFill ),'linewidth',1);
    %                         title(app.UIAxes_3,'NUS ZeroFill Spectrum');
                            xlabel(app.UIAxes_3, app.xlname), ylabel(app.UIAxes_3,'normalized (a.u.)');
                            set(app.UIAxes_3,'linewidth',1,'fontweight','bold')
                        else
                            SpecZeroFill = zeros(length(app.mask),size(app.y,2));
                            for it =1:app.np
                                SpecZeroFill(:,it) = abs(fftshift(fft(K'*app.y(:,it))));  %
                            end
                            contour(app.UIAxes_3,SpecZeroFill /max(SpecZeroFill(:)),'linewidth',1);
%                             title('NUS ZeroFill Spectrum')
                            xlabel(app.UIAxes_3,'Direct Dimension'), ylabel(app.UIAxes_3,'Indirect Dimension')
                            set(app.UIAxes_3,'linewidth',1,'fontweight','bold')
                        end
                    end
                end
            end
        end

        % Callback function
        function LoadfromworkspaceMenuSelected(app, event)
            app.mask = evalin('base', 'mask');
            app.y = evalin('base', 'y');
            app.xlname = 'Chemical Shift (ppm)';
            try app.cs = evalin('base', 'cs');
            catch
                app.cs = linspace(-0.5,0.5,length(app.mask));
                app.xlname = '\omega (2\pi)';
            end
            if ~isequal(app.mask, 0) && ~isequal(app.y, 0)
                app.SaveButton.Enable = false;
                app.SeparatePlotButton.Enable = false;
                app.LambdaButton.Enable = true;
                app.MaxitButton.Enable = true;
                app.StartButton.Enable = true;
                app.np = size(app.y, 2);
                if app.np == 1
                    K = Mask(app.mask);
                    SpecZeroFill = abs(fftshift(fft(K'*app.y)));
                    plot(app.UIAxes_3,app.cs,SpecZeroFill /max(SpecZeroFill ),'linewidth',1);
%                         title(app.UIAxes_3,'NUS ZeroFill Spectrum');
                    xlabel(app.UIAxes_3, app.xlname), ylabel(app.UIAxes_3,'normalized (a.u.)');
                    set(app.UIAxes_3,'linewidth',1,'fontweight','bold')
                else
                    SpecZeroFill = zeros(length(app.mask),size(app.y,2));
                    for it =1:app.np
                        SpecZeroFill(:,it) = abs(fftshift(fft(K'*app.y(:,it))));  %
                    end
                    contour(app.UIAxes_3,SpecZeroFill /max(SpecZeroFill(:)),'linewidth',1);
%                             title('NUS ZeroFill Spectrum')
                    xlabel(app.UIAxes_3,'Direct Dimension'), ylabel(app.UIAxes_3,'Indirect Dimension')
                    set(app.UIAxes_3,'linewidth',1,'fontweight','bold')
                end
            elseif isequal(app.mask, 0)
                errordlg('There is no mask in the workspace!', 'Error');
            elseif isequal(app.y, 0)
                errordlg('There is no y in the workspace!', 'Error');
            end
        end

        % Callback function
        function LambdaButtonPushed(app, event)
            app.lambda = app.LambdaEditField.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global SpecRec;
%             global cs;
            app.lambda = app.LambdaEditField.Value;
            app.maxit = app.MaxitEditField.Value;
            K = Mask(app.mask);
            N = length(app.mask);
            hr = round(N/2); hc = N+1-hr;
%             if exist("cs") ~=1
%                cs = linspace(0,1,N);  
%             end
            GR_InPara.K = K;
            GR_InPara.lambda = [app.lambda];
            GR_InPara.A = {HMOperator(hr,hc )};
            GR_InPara.mu = 1;
            GR_InPara.maxit = app.maxit;
            GR_InPara.RegTerm = {'Nuclear'};
            GR_InPara.Lxp = HMInv(app.mask,GR_InPara.mu,hr,hc);
            
            app.x = zeros(length(app.mask),size(app.y,2));
            fprintf('This data consist of %d signals in direct dimension \n',app.np)
            for it = 1: app.np
                NorFac = max(abs(app.y(:,it)));
                GR_InPara.y = app.y(:,it)/NorFac;
                [ GR_Out ] = GRIN_Sovler2( GR_InPara);
                app.x(:,it) = GR_Out.x*NorFac;
                fprintf('Completing reconstruction  %d out of %d signal(s) \n',it,app.np)
            end
            
            if ~isequal(app.x, 0)
                app.SaveButton.Enable = true;
                app.SeparatePlotButton.Enable = true;
                app.StartButton.Enable = false;
                if app.np == 1
                    SpecRec = abs(fftshift(fft(app.x)));
                    plot(app.UIAxes_4,app.cs,SpecRec/max(SpecRec),'linewidth',1);
    %                 title(app.UIAxes_4,'Reconstructed Spectrum')
                    xlabel(app.UIAxes_4,app.xlname), ylabel(app.UIAxes_4,'normalized (a.u.)');
                    set(app.UIAxes_4,'linewidth',1,'fontweight','bold')
                    if strcmp(app.xlname,'Chemical Shift (ppm)')
                      set(app.UIAxes_4,'xdir','reverse');
                    end  
                else
                    SpecRec = abs(fftshift(fft(app.x,[],1),1));
                    contour(app.UIAxes_4,SpecRec/max(SpecRec(:)),'linewidth',1);
%                     title('Reconstructed Spectrum')
                    xlabel(app.UIAxes_4,'Direct Dimension'), ylabel(app.UIAxes_4,'Indirect Dimension')
                    set(app.UIAxes_4,'linewidth',1,'fontweight','bold')
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global SpecRec;
%             global cs;

            if ~isequal(app.x, 0)
                GRIN_Out = struct; % create the struct variable to be saved
                GRIN_Out.fid_denoise = app.x;   %  
                GRIN_Out.spec_denoise = SpecRec;
                GRIN_Out.cs = app.cs;% chemical shift
                GRIN_Out.datetime =  datetime('now');
                GRIN_Out.application = 'Hankel Low-Rank NMR NUS Reconstruction';
            [filename, pathname] = uiputfile({ '*.mat';'*.*'}, 'load');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return;
            else
                str = [pathname, filename]; 
                save(str, "GRIN_Out", '-mat');
            end
            else
                errordlg('Please start to solve firstly!', 'Error');
            end
        end

        % Button pushed function: SeparatePlotButton
        function SeparatePlotButtonPushed(app, event)
            global SpecRec;
            figure;
            if app.np == 1
                SpecRec = abs(fftshift(fft(app.x)));
                plot(app.cs,SpecRec/max(SpecRec),'linewidth',1);
%                 title(app.UIAxes_4,'Reconstructed Spectrum')
                xlabel(app.xlname), ylabel('normalized (a.u.)');
                set(gca,'linewidth',1,'fontweight','bold')
                if strcmp(app.xlname,'Chemical Shift (ppm)')
                  set(gca,'xdir','reverse');
                end  
            else
                SpecRec = abs(fftshift(fft(app.x,[],1),1));
                contour(SpecRec/max(SpecRec(:)),'linewidth',1);
%                     title('Reconstructed Spectrum')
                xlabel('Direct Dimension'), ylabel('Indirect Dimension')
                set(gca,'linewidth',1,'fontweight','bold')
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Callingapp.HankelLowRankNMRNUSReconstructionButton.Enable = 'on';
%             Home;
            delete(app);
        end

        % Menu selected function: HomeMenu
        function HomeMenuSelected(app, event)
            app.Callingapp.HankelLowRankNMRNUSReconstructionButton.Enable = 'on';
%             Home;
            delete(app);
        end

        % Callback function
        function MaxitButtonPushed(app, event)
            app.maxit = app.MaxitEditField.Value;
        end

        % Callback function
        function LoadfrompathMenuSelected(app, event)
            [filename] = uigetdir();
            if isequal(filename, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return
            else
                data_list = dir([filename]);
                data_num = length(data_list);
                if data_num < 1
                    errordlg('The no data in the path! Please check again!', 'Error');
                else
                    for i = 1 : data_num
                        mat_name = data_list(i).name;
                        switch mat_name
                            case 'mask.mat'
                                mask_str = load(fullfile(filename, mat_name));
                                app.mask = mask_str.mask;
                            case 'y.mat'
                                y_str = load(fullfile(filename, mat_name));
                                app.y = y_str.y;
                        end
                    end
                    if ~isequal(app.mask, 0) && ~isequal(app.y, 0)
                        app.SaveButton.Enable = false;
                        app.SeparatePlotButton.Enable = false;
                        app.LambdaButton.Enable = true;
                        app.MaxitButton.Enable = true;
                        app.StartButton.Enable = true;
                        app.cs = linspace(0, 1, length(app.mask));
                        K = Mask(app.mask);
                        SpecZeroFill = abs(fftshift(fft(K'*app.y)));
                        plot(app.UIAxes_3,app.cs,SpecZeroFill /max(SpecZeroFill ),'linewidth',1);
%                         title(app.UIAxes_3,'NUS ZeroFill Spectrum');
                        xlabel(app.UIAxes_3,'chemical shift (ppm)'), ylabel(app.UIAxes_3,'normalized (a.u.)');
                    elseif isequal(app.mask, 0)
                        errordlg('There is no mask in the workspace!', 'Error');
                    elseif isequal(app.y, 0)
                        errordlg('There is no y in the workspace!', 'Error');
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.MenuSelectedFcn = createCallbackFcn(app, @HomeMenuSelected, true);
            app.HomeMenu.Text = 'Home';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadfromFileMenu = uimenu(app.FileMenu);
            app.LoadfromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromFileMenuSelected, true);
            app.LoadfromFileMenu.Text = 'Load from File';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.Panel);
            app.NUSZeroFillSpectrumLabel = uilabel(app.Panel);
            app.NUSZeroFillSpectrumLabel.Text = 'NUS ZeroFill Spectrum';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes_4 = uiaxes(app.Panel_2);
            app.ReconstructedSpectrumLabel = uilabel(app.Panel_2);
            app.ReconstructedSpectrumLabel.Text = 'Reconstructed Spectrum';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_4 = uipanel(app.Panel_3);
            app.LambdaEditField = uieditfield(app.Panel_4, 'numeric');
            app.MaxitEditField = uieditfield(app.Panel_4, 'numeric');
            app.LambdaLabel = uilabel(app.Panel_4);
            app.LambdaLabel.Text = 'Lambda:';
            app.MaxitLabel = uilabel(app.Panel_4);
            app.MaxitLabel.Text = 'Maxit:';
            app.StartButton = uibutton(app.Panel_3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.Panel_3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SeparatePlotButton = uibutton(app.Panel_3, 'push');
            app.SeparatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeparatePlotButtonPushed, true);
            app.SeparatePlotButton.Text = 'Separate Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HLRNUSRec(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end