classdef Home < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        HelpMenu               matlab.ui.container.Menu
        AboutMenu              matlab.ui.container.Menu
        Panel                  matlab.ui.container.Panel
        Label_10               matlab.ui.control.Label
        Label_9                matlab.ui.control.Label
        Label_8                matlab.ui.control.Label
        Label_7                matlab.ui.control.Label
        Label_6                matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        Label_11               matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        UserDesignedRegularizedInverseProblemButton  matlab.ui.control.Button
        DLaplaceNMRSmoothInversionButton  matlab.ui.control.Button
        DLaplaceNMRSparseInversionButton  matlab.ui.control.Button
        HankelLowRankNMRNUSReconstructionButton  matlab.ui.control.Button
        HankelLowRankNMRSpectraDenoiseButton  matlab.ui.control.Button
        CompressedSensingNUSNMRReconstructionButton  matlab.ui.control.Button
        CompressedSensingMRIReconstructionButton  matlab.ui.control.Button
        DDOSYProcessingButton  matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        GRINGeneralizedRegularizedInversionforNMRLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Home';
        end

        % Button pushed function: DDOSYProcessingButton
        function DDOSYProcessingButtonPushed(app, event)
            app.DDOSYProcessingButton.Enable = 'off';
            DOSYLRSp(app);
%             close(app.UIFigure);
        end

        % Button pushed function: CompressedSensingMRIReconstructionButton
        function CompressedSensingMRIReconstructionButtonPushed(app, event)
            app.CompressedSensingMRIReconstructionButton.Enable = 'off';
            MRI(app);
%             close(app.UIFigure);
        end

        % Button pushed function: 
        % CompressedSensingNUSNMRReconstructionButton
        function CompressedSensingNUSNMRReconstructionButtonPushed(app, event)
            app.CompressedSensingNUSNMRReconstructionButton.Enable = 'off';
            CSNUS(app);
%             delete(app);
        end

        % Button pushed function: HankelLowRankNMRSpectraDenoiseButton
        function HankelLowRankNMRSpectraDenoiseButtonPushed(app, event)
            app.HankelLowRankNMRSpectraDenoiseButton.Enable = 'off';
            HLRDenoise(app);
%             delete(app);
        end

        % Button pushed function: HankelLowRankNMRNUSReconstructionButton
        function HankelLowRankNMRNUSReconstructionButtonPushed(app, event)
            app.HankelLowRankNMRNUSReconstructionButton.Enable = 'off';
            HLRNUSRec(app);
%             delete(app);
        end

        % Button pushed function: DLaplaceNMRSparseInversionButton
        function DLaplaceNMRSparseInversionButtonPushed(app, event)
            app.DLaplaceNMRSparseInversionButton.Enable = 'off';
            HP_DT2(app);
%             delete(app);
        end

        % Button pushed function: DLaplaceNMRSmoothInversionButton
        function DLaplaceNMRSmoothInversionButtonPushed(app, event)
            app.DLaplaceNMRSmoothInversionButton.Enable = 'off';
            T2T2(app);
%             delete(app);
        end

        % Button pushed function: 
        % UserDesignedRegularizedInverseProblemButton
        function UserDesignedRegularizedInverseProblemButtonPushed(app, event)
%             app.UserDesignedRegularizedInverseProblemButton.Enable = 'off';
%             UserDesign;
            i = 1;
            while true
                new_name = strcat('UserDesign', num2str(i), '.mlx');
%                 new_file = fullfile("UserDesign", new_name);
                if exist(new_name, 'file')
                    i = i + 1;
                else
                    break
                end
            end
%             old_file = fullfile("UserDesign", 'UserDesign.mlx');
%             old_name = 'UserDesign.mlx';
            old_file = which('UserDesign.mlx');
            copyfile(old_file, new_name);
            open(new_name);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GRINGeneralizedRegularizedInversionforNMRLabel = uilabel(app.UIFigure);
            app.GRINGeneralizedRegularizedInversionforNMRLabel.Text = 'GRIN: Generalized Regularized Inversion for NMR';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Click the options below for a specific NMR inverse problem. If your inverse problem  is  not  included  now, please  select  the  last  option  for  a User-Designed Regularized Inverse Problem. More specific inverse problems will be created soon for the convenience of users. ';
            app.Panel = uipanel(app.UIFigure);
            app.DDOSYProcessingButton = uibutton(app.Panel, 'push');
            app.DDOSYProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @DDOSYProcessingButtonPushed, true);
            app.DDOSYProcessingButton.Text = '2D DOSY Processing';
            app.CompressedSensingMRIReconstructionButton = uibutton(app.Panel, 'push');
            app.CompressedSensingMRIReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @CompressedSensingMRIReconstructionButtonPushed, true);
            app.CompressedSensingMRIReconstructionButton.Text = 'Compressed Sensing MRI Reconstruction';
            app.CompressedSensingNUSNMRReconstructionButton = uibutton(app.Panel, 'push');
            app.CompressedSensingNUSNMRReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @CompressedSensingNUSNMRReconstructionButtonPushed, true);
            app.CompressedSensingNUSNMRReconstructionButton.Text = ' Compressed Sensing NUS NMR Reconstruction';
            app.HankelLowRankNMRSpectraDenoiseButton = uibutton(app.Panel, 'push');
            app.HankelLowRankNMRSpectraDenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @HankelLowRankNMRSpectraDenoiseButtonPushed, true);
            app.HankelLowRankNMRSpectraDenoiseButton.Text = 'Hankel Low-Rank NMR Spectra Denoise';
            app.HankelLowRankNMRNUSReconstructionButton = uibutton(app.Panel, 'push');
            app.HankelLowRankNMRNUSReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @HankelLowRankNMRNUSReconstructionButtonPushed, true);
            app.HankelLowRankNMRNUSReconstructionButton.Text = 'Hankel Low-Rank NMR NUS Reconstruction';
            app.DLaplaceNMRSparseInversionButton = uibutton(app.Panel, 'push');
            app.DLaplaceNMRSparseInversionButton.ButtonPushedFcn = createCallbackFcn(app, @DLaplaceNMRSparseInversionButtonPushed, true);
            app.DLaplaceNMRSparseInversionButton.Text = '2D Laplace NMR Sparse Inversion';
            app.DLaplaceNMRSmoothInversionButton = uibutton(app.Panel, 'push');
            app.DLaplaceNMRSmoothInversionButton.ButtonPushedFcn = createCallbackFcn(app, @DLaplaceNMRSmoothInversionButtonPushed, true);
            app.DLaplaceNMRSmoothInversionButton.Text = '2D Laplace NMR Smooth Inversion';
            app.UserDesignedRegularizedInverseProblemButton = uibutton(app.Panel, 'push');
            app.UserDesignedRegularizedInverseProblemButton.ButtonPushedFcn = createCallbackFcn(app, @UserDesignedRegularizedInverseProblemButtonPushed, true);
            app.UserDesignedRegularizedInverseProblemButton.Text = 'User-Designed Regularized Inverse Problem';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Ⅰ ：';
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = 'Ⅱ :';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = 'Ⅲ ：';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = 'Ⅳ ：';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = 'Ⅴ ：';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = 'Ⅵ :';
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = 'Ⅶ ：';
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = 'Ⅷ ：';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Home

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end