classdef MRI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        HomeMenu                matlab.ui.container.Menu
        FileMenu                matlab.ui.container.Menu
        LoaddataFileMenu        matlab.ui.container.Menu
        Panel_3                 matlab.ui.container.Panel
        SeparatePlotButton      matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        ParameterSettingButton  matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        Panel_2                 matlab.ui.container.Panel
        ReconstructedLabel      matlab.ui.control.Label
        UIAxes_2                matlab.ui.control.UIAxes
        Panel                   matlab.ui.container.Panel
        SamplingMaskLabel       matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mask = 0; % Description
        y = 0;
        lambda = 0.01;
        filtype = 'Daubechies';
        filsize = 4;
        wavesc = 4;
        DM = 0;
        xdif = 0;
        x_real = 0;
        x_imag = 0;
        x_abs = 0;
        x = 0;
        x_disp = 0;
        Callingapp;
        maxit = 100;
    end
    
    methods (Access = public)
        
        function updataParameter(app, lambda, filtype, filsize, wavesc, maxit)
            app.lambda = lambda;
            app.filtype = filtype;
            app.filsize = filsize;
            app.wavesc = wavesc;
            app.maxit = maxit;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Name = 'Compressed Sensing MRI Reconstruction';
            app.Callingapp = mainapp;
            app.SaveButton.Enable = false;
            app.SeparatePlotButton.Enable = false;
        end

        % Menu selected function: LoaddataFileMenu
        function LoaddataFileMenuSelected(app, event)
            app.ParameterSettingButton.Enable = true;
            app.StartButton.Enable = true;
            app.SaveButton.Enable = false;
            app.SeparatePlotButton.Enable = false;

            [filename, pathname] = uigetfile({'*.mat'});
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return
            else
                data_path = [pathname, filename];
                data_str = load(data_path);
                if ~isequal(data_str, [])
                    app.mask = data_str.GRIN_In.mask;
                    app.y = data_str.GRIN_In.y;
                    if ~isequal(app.mask, 0) && ~isequal(app.y, 0)
                        imshow(app.mask, 'Parent', app.UIAxes);
%                         title(app.UIAxes, 'Sampling Mask');
                    end
                end
            end 
        end

        % Callback function
        function LoaddatafromworkspaceMenuSelected(app, event)
            app.ParameterSettingButton.Enable = true;
            app.StartButton.Enable = true;
            app.SaveButton.Enable = false;
            app.SeparatePlotButton.Enable = false;
            
            app.mask = evalin('base', 'mask');
            app.y = evalin('base', 'y');

            if ~isequal(app.mask, 0) && ~isequal(app.y, 0)
                imshow(app.mask, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Sampling Mask');
            elseif isequal(app.mask, 0)
                errordlg('There is no mask in the workspace!', 'Error');
            elseif isequal(app.y, 0)
                errordlg('There is no y in the workspace!', 'Error');
            end
        end

        % Button pushed function: ParameterSettingButton
        function ParameterSettingButtonPushed(app, event)
            app.ParameterSettingButton.Enable = 'off';
            MRIParameter(app, app.lambda, app.filtype, app.filsize, app.wavesc, app.maxit);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            [row,column] = size(app.mask);
            K =  Fu_downsample(app.mask,row,column);
            complex_yes = ~isreal(app.y); 
            psi = Rice_Wavelet(row, column, app.wavesc, app.filtype, app.filsize, complex_yes);  %  wavelet operator
            
            GR_InPara.K = K;
            GR_InPara.y = app.y;
            GR_InPara.lambda = app.lambda;
            GR_InPara.A = {psi};
            GR_InPara.mu = 1;
            GR_InPara.maxit = app.maxit;
            GR_InPara.RegTerm = {'L1'};
            GR_InPara.Lxp = ImInv(app.mask,GR_InPara.mu);
            [ GR_Out ] = GRIN_Sovler2( GR_InPara);
            app.x = GR_Out.x;
            app.xdif = GR_Out.xdif;
            app.x_real = real(app.x);
            app.x_real = app.x_real./max(app.x_real(:));
            app.x_imag = imag(app.x);
            app.x_imag = app.x_imag./max(app.x_imag(:));
            app.x_abs = abs(app.x);
            app.x_abs = app.x_abs./max(app.x_abs(:));

            if ~isequal(app.x, 0)
                app.ParameterSettingButton.Enable = false;
                app.StartButton.Enable = false;
                app.SaveButton.Enable = true;
                app.SeparatePlotButton.Enable = true;

                switch app.DM
                    case 0
                        app.x_disp = app.x_real;
                    case 1
                        app.x_disp = app.x_imag;
                    case 2
                        app.x_disp = app.x_abs;
                end
                imshow(app.x_disp, 'Parent', app.UIAxes_2);
%                 title(app.UIAxes_2, 'Reconstructed')
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~isequal(app.x, 0)
                GRIN_Out = struct; % create the struct variable to be saved
                GRIN_Out.x = app.x;    
                GRIN_Out.x_real = app.x_real;
                GRIN_Out.x_imag = app.x_imag;
                GRIN_Out.x_abs = app.x_abs;
                GRIN_Out.mask = app.mask;
                GRIN_Out.datetime =  datetime('now');
                GRIN_Out.application = 'Compressed Sensing MRI Reconstruction';

                [filename, pathname] = uiputfile({ '*.mat';'*.*'}, 'load');
                if isequal(filename, 0) || isequal(pathname, 0)
                    errordlg('The file is not exit! Please check again!', 'Error');
                    return;
                else
                    str = [pathname, filename]; 
                    save(str, "GRIN_Out", '-mat');
                end
            else
                errordlg('Please start to solve firstly!', 'Error');
            end
        end

        % Button pushed function: SeparatePlotButton
        function SeparatePlotButtonPushed(app, event)
            figure, imshow(app.x_disp),title('Reconstructed');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Callingapp.CompressedSensingMRIReconstructionButton.Enable = 'on';
%             Home;
            delete(app);
        end

        % Menu selected function: HomeMenu
        function HomeMenuSelected(app, event)
            app.Callingapp.CompressedSensingMRIReconstructionButton.Enable = 'on';
%             Home;
            delete(app);
        end

        % Callback function
        function LoaddatafrompathMenuSelected(app, event)
            app.ParameterSettingButton.Enable = true;
            app.StartButton.Enable = true;
            app.SaveButton.Enable = false;
            app.SeparatePlotButton.Enable = false;

            [filename] = uigetdir();
            if isequal(filename, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return
            else
                data_list = dir([filename]);
                data_num = length(data_list);
                if data_num < 1
                    errordlg('The no data in the path! Please check again!', 'Error');
                else
                    for i = 1 : data_num
                        mat_name = data_list(i).name;
                        switch mat_name
                            case 'mask.mat'
                                mask_str = load(fullfile(filename, mat_name));
                                app.mask = mask_str.mask;
                            case 'y.mat'
                                y_str = load(fullfile(filename, mat_name));
                                app.y = y_str.y;
                        end
                    end
                    if ~isequal(app.mask, 0) && ~isequal(app.y, 0)
                        imshow(app.mask, 'Parent', app.UIAxes);
%                         title(app.UIAxes, 'Sampling Mask');
                    elseif isequal(app.mask, 0)
                        errordlg('There is no mask in the workspace!', 'Error');
                    elseif isequal(app.y, 0)
                        errordlg('There is no y in the workspace!', 'Error');
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.MenuSelectedFcn = createCallbackFcn(app, @HomeMenuSelected, true);
            app.HomeMenu.Text = 'Home';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoaddataFileMenu = uimenu(app.FileMenu);
            app.LoaddataFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddataFileMenuSelected, true);
            app.LoaddataFileMenu.Text = 'Load data File';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            app.SamplingMaskLabel = uilabel(app.Panel);
            app.SamplingMaskLabel.Text = 'Sampling Mask';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel_2);
            app.ReconstructedLabel = uilabel(app.Panel_2);
            app.ReconstructedLabel.Text = 'Reconstructed';
            app.Panel_3 = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.Panel_3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ParameterSettingButton = uibutton(app.Panel_3, 'push');
            app.ParameterSettingButton.ButtonPushedFcn = createCallbackFcn(app, @ParameterSettingButtonPushed, true);
            app.ParameterSettingButton.Text = 'Parameter Setting';
            app.SaveButton = uibutton(app.Panel_3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SeparatePlotButton = uibutton(app.Panel_3, 'push');
            app.SeparatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeparatePlotButtonPushed, true);
            app.SeparatePlotButton.Text = 'Separate Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end