classdef MRIParameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OKButton                  matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        FilterTypeDropDown        matlab.ui.control.DropDown
        FilterTypeDropDownLabel   matlab.ui.control.Label
        FilterSizeEditField       matlab.ui.control.NumericEditField
        FilterSizeEditFieldLabel  matlab.ui.control.Label
        WaveScaleEditField        matlab.ui.control.NumericEditField
        FilterTypeLabel           matlab.ui.control.Label
        WaveletSettingLabel       matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        MaxitEditField            matlab.ui.control.NumericEditField
        MaxitEditFieldLabel       matlab.ui.control.Label
        LambdaEditField           matlab.ui.control.NumericEditField
        LambdaLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        Callingapp; % Description
%         lambda = 0.01;
%         filtype = 'Haar';
%         filsize = 4;
%         wavesc = 4;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, lambda, filtype, filsize, wavesc, maxit)
            app.Callingapp = mainapp;
            app.UIFigure.WindowStyle = 'alwaysontop';
            app.UIFigure.Position(1) = mainapp.UIFigure.Position(1);
            app.UIFigure.Position(2) = mainapp.UIFigure.Position(2);
            app.LambdaEditField.Value = lambda;
            app.FilterTypeDropDown.Value = filtype;
            app.FilterSizeEditField.Value = filsize;
            app.WaveScaleEditField.Value = wavesc;
            app.MaxitEditField.Value = maxit;
            app.UIFigure.Name = 'Paremeter Setting';
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            lambda = app.LambdaEditField.Value;
            filsize = app.FilterSizeEditField.Value;
            filtype = app.FilterTypeDropDown.Value;
            wavesc = app.WaveScaleEditField.Value;
            maxit = app.MaxitEditField.Value;
            updataParameter(app.Callingapp, lambda, filtype, filsize, wavesc, maxit);
            app.Callingapp.ParameterSettingButton.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Callingapp.ParameterSettingButton.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.LambdaLabel = uilabel(app.Panel);
            app.LambdaLabel.Text = 'Lambda :';
            app.LambdaEditField = uieditfield(app.Panel, 'numeric');
            app.MaxitEditFieldLabel = uilabel(app.Panel);
            app.MaxitEditFieldLabel.Text = 'Maxit :';
            app.MaxitEditField = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.UIFigure);
            app.WaveletSettingLabel = uilabel(app.Panel_2);
            app.WaveletSettingLabel.Text = 'Wavelet Setting :';
            app.FilterTypeLabel = uilabel(app.Panel_2);
            app.FilterTypeLabel.Text = 'Wave Scale :';
            app.WaveScaleEditField = uieditfield(app.Panel_2, 'numeric');
            app.FilterSizeEditFieldLabel = uilabel(app.Panel_2);
            app.FilterSizeEditFieldLabel.Text = 'Filter Size :';
            app.FilterSizeEditField = uieditfield(app.Panel_2, 'numeric');
            app.FilterTypeDropDownLabel = uilabel(app.Panel_2);
            app.FilterTypeDropDownLabel.Text = 'Filter Type :';
            app.FilterTypeDropDown = uidropdown(app.Panel_2);
            app.FilterTypeDropDown.Items = {'Haar', 'Symmlet', 'Vaidyanathan', 'Battle', 'Beclkin', 'Coiflet', 'Daubechies'};
            app.FilterTypeDropDown.Value = 'Daubechies';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRIParameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end