classdef T2T2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HomeMenu                        matlab.ui.container.Menu
        FileMenu                        matlab.ui.container.Menu
        LoadfromFileMenu                matlab.ui.container.Menu
        Panel_2                         matlab.ui.container.Panel
        SeparatePlotButton              matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        CpnrLabel                       matlab.ui.control.Label
        ContourlevelLabel               matlab.ui.control.Label
        MaxitLabel                      matlab.ui.control.Label
        LambdaLabel                     matlab.ui.control.Label
        CpnrEditField                   matlab.ui.control.NumericEditField
        ContourLevelEditField           matlab.ui.control.NumericEditField
        MaxitEditField                  matlab.ui.control.NumericEditField
        K2ButtonGroup                   matlab.ui.container.ButtonGroup
        K2SpacedButtonGroup             matlab.ui.container.ButtonGroup
        SpacedLabel_2                   matlab.ui.control.Label
        K2Logarithm                     matlab.ui.control.RadioButton
        K2Liner                         matlab.ui.control.RadioButton
        NumEditField_2                  matlab.ui.control.NumericEditField
        NumEditField_2Label             matlab.ui.control.Label
        toEditField_2                   matlab.ui.control.NumericEditField
        toEditField_2Label              matlab.ui.control.Label
        fromEditField_2                 matlab.ui.control.NumericEditField
        fromEditField_2Label            matlab.ui.control.Label
        Kernel2decayconstantrangeLabel  matlab.ui.control.Label
        K2T2                            matlab.ui.control.RadioButton
        K2T1                            matlab.ui.control.RadioButton
        K2D                             matlab.ui.control.RadioButton
        K1ButtonGroup                   matlab.ui.container.ButtonGroup
        K1SpacedButtonGroup             matlab.ui.container.ButtonGroup
        SpacedLabel                     matlab.ui.control.Label
        K1Logarithm                     matlab.ui.control.RadioButton
        K1Liner                         matlab.ui.control.RadioButton
        NumEditField                    matlab.ui.control.NumericEditField
        NumEditFieldLabel               matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        fromEditField                   matlab.ui.control.NumericEditField
        fromEditFieldLabel              matlab.ui.control.Label
        Kernel1decayconstantrangeLabel  matlab.ui.control.Label
        K1T2                            matlab.ui.control.RadioButton
        K1T1                            matlab.ui.control.RadioButton
        K1D                             matlab.ui.control.RadioButton
        LambdaEditField                 matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        ResultLabel                     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        lambda = 0.0001;
        v1 = 0;
        v2 = 0;
        y = 0;
        K1Para = 0;
        K2Para = 0;
        K1 = 0;
        K2 = 0;
        cpnr = 0.1;
        x = 0;
        objval = 0;
        xdif = 0;
        Callingapp;
        maxit = 5000;
        contourlevel = 30;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Name = '2D Laplace NMR Smooth Inversion';
            app.LambdaEditField.Value = app.lambda;
            app.K1Para = struct;
            app.K1Para.Model = 'T2'; 
            app.K1Para.Spaced = 1; % 0 for linear, 1 for logarithm
            app.K1Para.Num = 32; % the number of atoms
            app.K1Para.ns = 1e-3;
            app.K1Para.ne = 1e-1;
            app.K1Logarithm.Value = true;

            app.K2Para = struct;
            app.K2Para.Model = 'T2'; 
            app.K2Para.Spaced = 1; % 0 for linear, 1 for logarithm
            app.K2Para.Num = 32; % the number of atoms
            app.K2Para.ns = 1e-3;
            app.K2Para.ne = 1e-1;
            app.K2Logarithm.Value = true;

            app.fromEditField.Value = app.K1Para.ns;
            app.toEditField.Value = app.K1Para.ne;
            app.NumEditField.Value = app.K1Para.Num;
            
            app.fromEditField_2.Value = app.K2Para.ns;
            app.toEditField_2.Value = app.K2Para.ne;
            app.NumEditField_2.Value = app.K2Para.Num;
            app.K1T2.Value = true;
            app.K2T2.Value = true;

            app.Callingapp = mainapp;
            app.MaxitEditField.Value = app.maxit;
            app.ContourLevelEditField.Value = app.contourlevel;
            app.CpnrEditField.Value = app.cpnr;
            app.SaveButton.Enable = false;
            app.SeparatePlotButton.Enable = false;
%             app.K1ButtonGroup.SelectedObject.Value = true;
%             app.K1ButtonGroup.SelectedObject.Text = 'T1';
        end

        % Menu selected function: LoadfromFileMenu
        function LoadfromFileMenuSelected(app, event)
            [filename, pathname] = uigetfile({'*.mat'});
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return
            else
                data_path = [pathname, filename];
                data_str = load(data_path);
                if ~isequal(data_str, [])
                    app.v1 = data_str.GRIN_In.v1;
                    app.v2 = data_str.GRIN_In.v2;
                    app.y = data_str.GRIN_In.y;
                    if ~isequal(app.v1, 0) && ~isequal(app.y, 0) && ~isequal(app.v2, 0)
                        app.SaveButton.Enable = false;
                        app.SeparatePlotButton.Enable = false;
                        app.StartButton.Enable = true;
                        
                        app.v1 = app.v1(:);
                        app.v2 = app.v2(:);
%                         errordlg('Load data from file successfully!', 'Nice');
                        xpos = get(app.UIAxes, 'xlim');
                        ypos = get(app.UIAxes, 'ylim');
                        text(app.UIAxes,(xpos(1)+xpos(2))/4, (ypos(1) + ypos(2))/2, 'Load data from file successfully!', 'Color', 'red', 'FontSize', 14);
                    end
                end
            end 
        end

        % Callback function
        function LoadfromworkspaceMenuSelected(app, event)
            app.v1 = evalin('base', 'v1');
            app.v2 = evalin('base', 'v2');
            app.y = evalin('base', 'y');

            if ~isequal(app.v1, 0) && ~isequal(app.y, 0) && ~isequal(app.v2, 0)
                app.SaveButton.Enable = false;
                app.SeparatePlotButton.Enable = false;
                app.ContourLevelButton.Enable = false;
                app.StartButton.Enable = true;
                app.LambdaButton.Enable = true;
                app.MaxitButton.Enable = true;
                app.OKButton.Enable = true;
                app.v1 = app.v1(:);
                app.v2 = app.v2(:);

                errordlg('Load data from workspace successfully!', 'Nice');
            elseif isequal(app.v1, 0)
                errordlg('There is no v1 in the workspace!', 'Error');
            elseif isequal(app.v2, 0)
                errordlg('There is no v2 in the workspace!', 'Error');
            elseif isequal(app.y, 0)
                errordlg('There is no y in the workspace!', 'Error');
            end
        end

        % Callback function
        function LambdaButtonPushed(app, event)
            app.lambda = app.LambdaEditField.Value;
        end

        % Selection changed function: K1ButtonGroup
        function K1ButtonGroupSelectionChanged(app, event)
            K1Button = app.K1ButtonGroup.SelectedObject;
            
            switch K1Button.Text
                case 'D'
                    app.K1Para.Model = 'D';
                case 'T1'
                    app.K1Para.Model = 'T1';
                case 'T2'
                    app.K1Para.Model = 'T2';
            end
        end

        % Selection changed function: K2ButtonGroup
        function K2ButtonGroupSelectionChanged(app, event)
            K2Button = app.K2ButtonGroup.SelectedObject;
            
            switch K2Button.Text
                case 'D'
                    app.K2Para.Model = 'D';
                case 'T1'
                    app.K2Para.Model = 'T1';
                case 'T2'
                    app.K2Para.Model = 'T2';
            end
        end

        % Callback function
        function OKButtonPushed(app, event)
            app.K1Para.ns = app.fromEditField.Value;
            app.K1Para.ne = app.toEditField.Value;
            app.K1Para.Num = app.NumEditField.Value;
            app.K2Para.ns = app.fromEditField_2.Value;
            app.K2Para.ne = app.toEditField_2.Value;
            app.K2Para.Num = app.NumEditField_2.Value;
            
%             cpnr = 0.1; % Default : the ratio of the number of PC to the number of components for SVD compression

        end

        % Selection changed function: K1SpacedButtonGroup
        function K1SpacedButtonGroupSelectionChanged(app, event)
            K1SpaceButton = app.K1SpacedButtonGroup.SelectedObject;
            
            switch K1SpaceButton.Text
                case 'Linear'
                    app.K1Para.Spaced = 0;
                case 'Logarithm'
                    app.K1Para.Spaced = 1;
            end
        end

        % Selection changed function: K2SpacedButtonGroup
        function K2SpacedButtonGroupSelectionChanged(app, event)
            K2SpaceButton = app.K2SpacedButtonGroup.SelectedObject;
            
            switch K2SpaceButton.Text
                case 'Linear'
                    app.K2Para.Spaced = 0;
                case 'Logarithm'
                    app.K2Para.Spaced = 1;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.lambda = app.LambdaEditField.Value;
            app.maxit = app.MaxitEditField.Value;
            app.cpnr = app.CpnrEditField.Value;

            app.K1Para.ns = app.fromEditField.Value;
            app.K1Para.ne = app.toEditField.Value;
            app.K1Para.Num = app.NumEditField.Value;
            app.K2Para.ns = app.fromEditField_2.Value;
            app.K2Para.ne = app.toEditField_2.Value;
            app.K2Para.Num = app.NumEditField_2.Value;

            if app.K1Para.Spaced
              app.K1Para.Range = logspace(log10(app.K1Para.ns),log10(app.K1Para.ne),app.K1Para.Num);
            else 
              app.K1Para.Range = linspace(app.K1Para.ns,app.K1Para.ne,app.K1Para.Num);
            end  
            switch app.K1Para.Model
                case 'D'
                   app.K1 = exp(-app.v1*app.K1Para.Range);
                   app.K1Para.Unit = 'm^2s^-^1 '; 
                case 'T1' 
                   app.K1 = 1-exp(-app.v1*1./(app.K1Para.Range));  
                   app.K1Para.Unit = 's'; 
                case 'T2'
                   app.K1 = exp(-app.v1*1./(app.K1Para.Range));  
                   app.K1Para.Unit = 's'; 
            end

            if app.K2Para.Spaced
              app.K2Para.Range= logspace(log10(app.K2Para.ns),log10(app.K2Para.ne),app.K2Para.Num);
            else 
              app.K2Para.Range = linspace(app.K2Para.ns,app.K2Para.ne,app.K2Para.Num);
            end
            switch app.K2Para.Model
                case 'D'
                   app.K2 = exp(-app.v2*app.K2Para.Range);
                   app.K2Para.Unit = 'm^2s^-^1 '; 
                case 'T1' 
                   app.K2 = 1-exp(-app.v2*1./(app.K2Para.Range));  
                   app.K2Para.Unit = 's'; 
                case 'T2'
                   app.K2 = exp(-app.v2*1./(app.K2Para.Range)); 
                   app.K2Para.Unit = 's';     
            end
            
            K = kron(app.K2, app.K1);
            app.y = app.y(:);
            NorFac = max(app.y);
            app.y = app.y./NorFac;
            % M = size(K,2); 
            % P1 = diag(ones(M-1,1),1); P2 = P1-diag(ones(M,1));
            % P3 = P2(1:end-1,:);P4=P3(1:end-1,1:end-1);
            % P = P4*P3;
            % ------- SVD Compression for accelerated reconstruction --------------------
            cpn = round(app.cpnr*size(K,1));
            [u,s,v] = svd(K,'econ');
            cpn = round(app.cpnr*size(s,1));
            u = u(:,1:cpn);
            app.y = u'*app.y;
            K = u'*K; 
            A = 1;
            GR_InPara.K = K;
            GR_InPara.y = app.y;
            GR_InPara.lambda = [app.lambda];
            GR_InPara.A = {A};
            GR_InPara.mu = 1;
            GR_InPara.maxit = app.maxit;
            GR_InPara.RegTerm = {'L2','Nonneg'};    %        
            [ GR_Out ] = GRIN_Sovler2( GR_InPara);
            app.xdif = GR_Out.xdif;
            app.objval = GR_Out.objval;
            app.x = GR_Out.x;
            
            if ~isequal(app.x, 0)
                app.SaveButton.Enable = true;
                app.SeparatePlotButton.Enable = true;
                app.StartButton.Enable = false;
                
                app.x =app.x*NorFac; 
                app.x = reshape(app.x,size(app.K1, 2),size(app.K2, 2) );
    
                contour(app.UIAxes, app.K2Para.Range, app.K1Para.Range, app.x, app.contourlevel);
                colorbar(app.UIAxes); %T2,Dshift,     K2Para.Range,K1Para.Range,
%                 title(app.UIAxes, 'result');
                set(app.UIAxes,'linewidth',1,'fontweight','bold'), % revised 20220406
                xlabel(app.UIAxes, [app.K2Para.Model,' (',app.K2Para.Unit,')']);
                ylabel(app.UIAxes, [app.K1Para.Model,' (',app.K1Para.Unit,')']); 
                set(app.UIAxes, 'xscale', 'log');
                set(app.UIAxes, 'yscale', 'log');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~isequal(app.x, 0)
                GRIN_Out = struct; % create the struct variable to be saved
                GRIN_Out.LSpec = app.x;   %  
                GRIN_Out.D1Kernel = app.K1Para;
                GRIN_Out.D2Kernel = app.K2Para;
                GRIN_Out.datetime =  datetime('now');
                GRIN_Out.application = '2D Laplace NMR Sparse Inversion';

                [filename, pathname] = uiputfile({ '*.mat';'*.*'}, 'load');
                if isequal(filename, 0) || isequal(pathname, 0)
                    errordlg('The file is not exit! Please check again!', 'Error');
                    return;
                else
                    str = [pathname, filename]; 
                    save(str, "GRIN_Out", '-mat');
                end
            else
                errordlg('Please start to solve firstly!', 'Error');
            end
        end

        % Button pushed function: SeparatePlotButton
        function SeparatePlotButtonPushed(app, event)
            figure;
            contour( app.K2Para.Range, app.K1Para.Range,app.x,app.contourlevel),colorbar; %T2,Dshift,     K2Para.Range,K1Para.Range,
            title('result');
            xlabel([ app.K2Para.Model,' (',app.K2Para.Unit,')']),ylabel( [app.K1Para.Model,' (',app.K1Para.Unit,')']); 
            set(gca,'XScale','log','YScale','log');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Callingapp.DLaplaceNMRSmoothInversionButton.Enable = 'on';
%             Home;
            delete(app);
        end

        % Menu selected function: HomeMenu
        function HomeMenuSelected(app, event)
            app.Callingapp.DLaplaceNMRSmoothInversionButton.Enable = 'on';
%             Home;
            delete(app);
        end

        % Callback function
        function MaxitButtonPushed(app, event)
            app.maxit = app.MaxitEditField.Value;
        end

        % Callback function
        function LoadfrompathMenuSelected(app, event)
            [filename] = uigetdir();
            if isequal(filename, 0)
                errordlg('The file is not exit! Please check again!', 'Error');
                return
            else
                data_list = dir([filename]);
                data_num = length(data_list);
                if data_num < 1
                    errordlg('The no data in the path! Please check again!', 'Error');
                else
                    for i = 1 : data_num
                        mat_name = data_list(i).name;
                        switch mat_name
                            case 'v1.mat'
                                v1_str = load(fullfile(filename, mat_name));
                                app.v1 = v1_str.v1;
                            case 'y.mat'
                                y_str = load(fullfile(filename, mat_name));
                                app.y = y_str.y;
                            case 'v2.mat'
                                v2_str = load(fullfile(filename, mat_name));
                                app.v2 = v2_str.v2;
                        end
                    end
                    if ~isequal(app.v1, 0) && ~isequal(app.y, 0) && ~isequal(app.v2, 0)
                        app.SaveButton.Enable = false;
                        app.SeparatePlotButton.Enable = false;
                        app.ContourLevelButton.Enable = false;
                        app.StartButton.Enable = true;
                        app.LambdaButton.Enable = true;
                        app.MaxitButton.Enable = true;
                        app.OKButton.Enable = true;
                        app.v1 = app.v1(:);
                        app.v2 = app.v2(:);
                        errordlg('Load data from path successfully!', 'Nice');
                    elseif isequal(app.v1, 0)
                        errordlg('There is no v1 in the workspace!', 'Error');
                    elseif isequal(app.v2, 0)
                        errordlg('There is no v2 in the workspace!', 'Error');
                    elseif isequal(app.y, 0)
                        errordlg('There is no y in the workspace!', 'Error');
                    end
                end
            end
        end

        % Callback function
        function ContourLevelButtonPushed(app, event)
            app.contourlevel = app.ContourLevelEditField.Value;
            contour(app.UIAxes, app.K2Para.Range, app.K1Para.Range, app.x, app.contourlevel);
            colorbar(app.UIAxes); %T2,Dshift,     K2Para.Range,K1Para.Range,
%             title(app.UIAxes, 'result');
            xlabel(app.UIAxes, [app.K2Para.Model,' (',app.K2Para.Unit,')']);
            ylabel(app.UIAxes, [app.K1Para.Model,' (',app.K1Para.Unit,')']); 
            set(app.UIAxes, 'xscale', 'log');
            set(app.UIAxes, 'yscale', 'log');
        end

        % Callback function
        function CpnrButtonPushed(app, event)
            app.cpnr = app.CpnrEditField.value;
        end

        % Value changed function: ContourLevelEditField
        function ContourLevelEditFieldValueChanged(app, event)
            app.contourlevel = app.ContourLevelEditField.Value;    
            contour(app.UIAxes, app.K2Para.Range, app.K1Para.Range, app.x, app.contourlevel);
            colorbar(app.UIAxes); %T2,Dshift,     K2Para.Range,K1Para.Range,
%                 title(app.UIAxes, 'result');
            set(app.UIAxes,'linewidth',1,'fontweight','bold'), % revised 20220406
            xlabel(app.UIAxes, [app.K2Para.Model,' (',app.K2Para.Unit,')']);
            ylabel(app.UIAxes, [app.K1Para.Model,' (',app.K1Para.Unit,')']); 
            set(app.UIAxes, 'xscale', 'log');
            set(app.UIAxes, 'yscale', 'log');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.MenuSelectedFcn = createCallbackFcn(app, @HomeMenuSelected, true);
            app.HomeMenu.Text = 'Home';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadfromFileMenu = uimenu(app.FileMenu);
            app.LoadfromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromFileMenuSelected, true);
            app.LoadfromFileMenu.Text = 'Load from File';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            zlabel(app.UIAxes, 'Z')
            app.ResultLabel = uilabel(app.Panel);
            app.ResultLabel.Text = 'Result';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_3 = uipanel(app.Panel_2);
            app.LambdaEditField = uieditfield(app.Panel_3, 'numeric');
            app.K1ButtonGroup = uibuttongroup(app.Panel_3);
            app.K1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @K1ButtonGroupSelectionChanged, true);
            app.K1D = uiradiobutton(app.K1ButtonGroup);
            app.K1D.Text = 'D';
            app.K1D.Value = true;
            app.K1T1 = uiradiobutton(app.K1ButtonGroup);
            app.K1T1.Text = 'T1';
            app.K1T2 = uiradiobutton(app.K1ButtonGroup);
            app.K1T2.Text = 'T2';
            app.Kernel1decayconstantrangeLabel = uilabel(app.K1ButtonGroup);
            app.Kernel1decayconstantrangeLabel.Text = 'Kernel1 decay constant range:';
            app.fromEditFieldLabel = uilabel(app.K1ButtonGroup);
            app.fromEditFieldLabel.Text = 'from';
            app.fromEditField = uieditfield(app.K1ButtonGroup, 'numeric');
            app.toEditFieldLabel = uilabel(app.K1ButtonGroup);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.K1ButtonGroup, 'numeric');
            app.NumEditFieldLabel = uilabel(app.K1ButtonGroup);
            app.NumEditFieldLabel.Text = 'Num:';
            app.NumEditField = uieditfield(app.K1ButtonGroup, 'numeric');
            app.K1SpacedButtonGroup = uibuttongroup(app.K1ButtonGroup);
            app.K1SpacedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @K1SpacedButtonGroupSelectionChanged, true);
            app.K1Liner = uiradiobutton(app.K1SpacedButtonGroup);
            app.K1Liner.Text = 'Linear';
            app.K1Liner.Value = true;
            app.K1Logarithm = uiradiobutton(app.K1SpacedButtonGroup);
            app.K1Logarithm.Text = 'Logarithm';
            app.SpacedLabel = uilabel(app.K1SpacedButtonGroup);
            app.SpacedLabel.Text = 'Spaced:';
            app.K2ButtonGroup = uibuttongroup(app.Panel_3);
            app.K2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @K2ButtonGroupSelectionChanged, true);
            app.K2D = uiradiobutton(app.K2ButtonGroup);
            app.K2D.Text = 'D';
            app.K2D.Value = true;
            app.K2T1 = uiradiobutton(app.K2ButtonGroup);
            app.K2T1.Text = 'T1';
            app.K2T2 = uiradiobutton(app.K2ButtonGroup);
            app.K2T2.Text = 'T2';
            app.Kernel2decayconstantrangeLabel = uilabel(app.K2ButtonGroup);
            app.Kernel2decayconstantrangeLabel.Text = 'Kernel2 decay constant range:';
            app.fromEditField_2Label = uilabel(app.K2ButtonGroup);
            app.fromEditField_2Label.Text = 'from';
            app.fromEditField_2 = uieditfield(app.K2ButtonGroup, 'numeric');
            app.toEditField_2Label = uilabel(app.K2ButtonGroup);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.K2ButtonGroup, 'numeric');
            app.NumEditField_2Label = uilabel(app.K2ButtonGroup);
            app.NumEditField_2Label.Text = 'Num:';
            app.NumEditField_2 = uieditfield(app.K2ButtonGroup, 'numeric');
            app.K2SpacedButtonGroup = uibuttongroup(app.K2ButtonGroup);
            app.K2SpacedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @K2SpacedButtonGroupSelectionChanged, true);
            app.K2Liner = uiradiobutton(app.K2SpacedButtonGroup);
            app.K2Liner.Text = 'Linear';
            app.K2Liner.Value = true;
            app.K2Logarithm = uiradiobutton(app.K2SpacedButtonGroup);
            app.K2Logarithm.Text = 'Logarithm';
            app.SpacedLabel_2 = uilabel(app.K2SpacedButtonGroup);
            app.SpacedLabel_2.Text = 'Spaced:';
            app.MaxitEditField = uieditfield(app.Panel_3, 'numeric');
            app.ContourLevelEditField = uieditfield(app.Panel_3, 'numeric');
            app.ContourLevelEditField.ValueChangedFcn = createCallbackFcn(app, @ContourLevelEditFieldValueChanged, true);
            app.CpnrEditField = uieditfield(app.Panel_3, 'numeric');
            app.LambdaLabel = uilabel(app.Panel_3);
            app.LambdaLabel.Text = 'Lambda:';
            app.MaxitLabel = uilabel(app.Panel_3);
            app.MaxitLabel.Text = 'Maxit:';
            app.ContourlevelLabel = uilabel(app.Panel_3);
            app.ContourlevelLabel.Text = 'Contourlevel:';
            app.CpnrLabel = uilabel(app.Panel_3);
            app.CpnrLabel.Text = 'Cpnr:';
            app.StartButton = uibutton(app.Panel_2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.Panel_2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SeparatePlotButton = uibutton(app.Panel_2, 'push');
            app.SeparatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeparatePlotButtonPushed, true);
            app.SeparatePlotButton.Text = 'Separate Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T2T2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end